classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        CalibrationTab              matlab.ui.container.Tab
        GridLayout8                 matlab.ui.container.GridLayout
        Button_2                    matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
        SamplenumberEditField       matlab.ui.control.NumericEditField
        UniteMagnet                 matlab.ui.control.NumericEditField
        BzEditField_2Label_4        matlab.ui.control.Label
        ContinuousSaveCheckBox      matlab.ui.control.CheckBox
        CalibParaTable              matlab.ui.control.Table
        CalibParaLabel              matlab.ui.control.Label
        SaveParamButton             matlab.ui.control.Button
        loadSampleButton            matlab.ui.control.Button
        SaveSampleButton            matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        CalculateButton             matlab.ui.control.Button
        BLETab                      matlab.ui.container.Tab
        GridLayout14                matlab.ui.container.GridLayout
        BlockCheckBox               matlab.ui.control.CheckBox
        ShuffleButton               matlab.ui.control.Button
        BLELengthEditField          matlab.ui.control.NumericEditField
        BLECloseButton              matlab.ui.control.Button
        ConnectLamp                 matlab.ui.control.Lamp
        ConnectLampLabel            matlab.ui.control.Label
        BLEConnectButton            matlab.ui.control.Button
        TextPanel                   matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        text_receive                matlab.ui.control.TextArea
        ReceivePanel                matlab.ui.container.Panel
        ReceivePanelGrid            matlab.ui.container.GridLayout
        autozeroCheckBox            matlab.ui.control.CheckBox
        kalmanCheckBox              matlab.ui.control.CheckBox
        RateEditField               matlab.ui.control.NumericEditField
        RateEditFieldLabel          matlab.ui.control.Label
        B_receive                   matlab.ui.control.NumericEditField
        BzEditField_2Label_3        matlab.ui.control.Label
        T_receive                   matlab.ui.control.NumericEditField
        BzEditField_2Label_2        matlab.ui.control.Label
        Bz_receive                  matlab.ui.control.NumericEditField
        BzEditField_2Label          matlab.ui.control.Label
        By_receive                  matlab.ui.control.NumericEditField
        ByEditField_2Label          matlab.ui.control.Label
        Bx_receive                  matlab.ui.control.NumericEditField
        BxEditField_2Label          matlab.ui.control.Label
        idSpinner_2Label            matlab.ui.control.Label
        id_receive                  matlab.ui.control.Spinner
        com_selection_receive       matlab.ui.control.DropDown
        portnumberLabel             matlab.ui.control.Label
        justbiasCheckBox            matlab.ui.control.CheckBox
        calibrationCheckBox         matlab.ui.control.CheckBox
        loadParametersButton        matlab.ui.control.Button
        get_geomagnetismButton      matlab.ui.control.Button
        close_serial_receive        matlab.ui.control.Button
        open_serial_receive         matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        DisplayPanel                matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        polyLineCheckBox            matlab.ui.control.CheckBox
        scatterCheckBox             matlab.ui.control.CheckBox
        LineCheckBox                matlab.ui.control.CheckBox
        ClearDataButton             matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        LineAxes                    matlab.ui.control.UIAxes
        SxAxes                      matlab.ui.control.UIAxes
        SyAxes                      matlab.ui.control.UIAxes
        ScatterAxes                 matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        Panel3                      matlab.ui.container.Panel
        GridLayout16                matlab.ui.container.GridLayout
        Continious_saveCheckBox     matlab.ui.control.CheckBox
        URCommandIndexSpinner       matlab.ui.control.Spinner
        indexLabel                  matlab.ui.control.Label
        labelTextArea               matlab.ui.control.TextArea
        labelTextAreaLabel          matlab.ui.control.Label
        URActionLen                 matlab.ui.control.NumericEditField
        Label_5                     matlab.ui.control.Label
        syncEditField               matlab.ui.control.NumericEditField
        synclabelCheckBox           matlab.ui.control.CheckBox
        URClearQueueButton          matlab.ui.control.Button
        ExecutePanelButton          matlab.ui.control.Button
        URExecuteButton             matlab.ui.control.Button
        URSyncButton                matlab.ui.control.Button
        URStopStatusButton          matlab.ui.control.StateButton
        URAutoLastButton            matlab.ui.control.StateButton
        URAutoNextButton            matlab.ui.control.StateButton
        SubButton_UR                matlab.ui.control.Button
        AddButton_UR                matlab.ui.control.Button
        TabGroup2                   matlab.ui.container.TabGroup
        URTab                       matlab.ui.container.Tab
        GridLayout12                matlab.ui.container.GridLayout
        Panel2                      matlab.ui.container.Panel
        coordinatetreePanel         matlab.ui.container.Panel
        GridLayout13                matlab.ui.container.GridLayout
        CaliUR                      matlab.ui.control.Button
        Button_7                    matlab.ui.control.Button
        SaveURPoseButton            matlab.ui.control.Button
        ReloadCoordTreeButton       matlab.ui.control.Button
        Button_6                    matlab.ui.control.Button
        PlotDigraphButton           matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        GridLayout11                matlab.ui.container.GridLayout
        UR_Queue_Initialize_Button  matlab.ui.control.Button
        URDropDown                  matlab.ui.control.DropDown
        Label_4                     matlab.ui.control.Label
        URTextArea                  matlab.ui.control.TextArea
        Label_3                     matlab.ui.control.Label
        BrowseButton                matlab.ui.control.Button
        LoadURMsgToFileButton       matlab.ui.control.Button
        SaveURMsgToFileButton       matlab.ui.control.Button
        DisplayTab                  matlab.ui.container.Tab
        GridLayout15                matlab.ui.container.GridLayout
        UR2StatusPane               matlab.ui.container.Panel
        GridLayout10_5              matlab.ui.container.GridLayout
        UR2EnableCheckBox           matlab.ui.control.CheckBox
        objectDropDown_3            matlab.ui.control.DropDown
        frameDropDown_3Label_6      matlab.ui.control.Label
        frameDropDown_3             matlab.ui.control.DropDown
        frameDropDown_6Label        matlab.ui.control.Label
        UR2modeDropDown             matlab.ui.control.DropDown
        StepEditField_3             matlab.ui.control.NumericEditField
        j6EditField_3               matlab.ui.control.Spinner
        j4Label_45                  matlab.ui.control.Label
        j5EditField_3               matlab.ui.control.Spinner
        j4Label_44                  matlab.ui.control.Label
        j4EditField_3               matlab.ui.control.Spinner
        j4Label_43                  matlab.ui.control.Label
        j3EditField_3               matlab.ui.control.Spinner
        j3Label_5                   matlab.ui.control.Label
        j2EditField_3               matlab.ui.control.Spinner
        j2Label_5                   matlab.ui.control.Label
        j1EditField_3               matlab.ui.control.Spinner
        j1SpinnerLabel_5            matlab.ui.control.Label
        rzEditField_3               matlab.ui.control.Spinner
        j4Label_42                  matlab.ui.control.Label
        ryEditField_3               matlab.ui.control.Spinner
        j4Label_41                  matlab.ui.control.Label
        rxEditField_3               matlab.ui.control.Spinner
        j4Label_40                  matlab.ui.control.Label
        zEditField_3                matlab.ui.control.Spinner
        j4Label_39                  matlab.ui.control.Label
        yEditField_3                matlab.ui.control.Spinner
        j4Label_38                  matlab.ui.control.Label
        xEditField_3                matlab.ui.control.Spinner
        j4Label_37                  matlab.ui.control.Label
        UR2_Lamp                    matlab.ui.control.Lamp
        UR1StatusPanel              matlab.ui.container.Panel
        GridLayout10_4              matlab.ui.container.GridLayout
        UR1EnableCheckBox           matlab.ui.control.CheckBox
        objectDropDown_2            matlab.ui.control.DropDown
        frameDropDown_3Label_5      matlab.ui.control.Label
        frameDropDown_2             matlab.ui.control.DropDown
        frameDropDown_5Label        matlab.ui.control.Label
        UR1modeDropDown             matlab.ui.control.DropDown
        StepEditField_2             matlab.ui.control.NumericEditField
        j6EditField_2               matlab.ui.control.Spinner
        j4Label_36                  matlab.ui.control.Label
        j5EditField_2               matlab.ui.control.Spinner
        j4Label_35                  matlab.ui.control.Label
        j4EditField_2               matlab.ui.control.Spinner
        j4Label_34                  matlab.ui.control.Label
        j3EditField_2               matlab.ui.control.Spinner
        j3Label_4                   matlab.ui.control.Label
        j2EditField_2               matlab.ui.control.Spinner
        j2Label_4                   matlab.ui.control.Label
        j1EditField_2               matlab.ui.control.Spinner
        j1SpinnerLabel_4            matlab.ui.control.Label
        rzEditField_2               matlab.ui.control.Spinner
        j4Label_33                  matlab.ui.control.Label
        ryEditField_2               matlab.ui.control.Spinner
        j4Label_32                  matlab.ui.control.Label
        rxEditField_2               matlab.ui.control.Spinner
        j4Label_31                  matlab.ui.control.Label
        zEditField_2                matlab.ui.control.Spinner
        j4Label_30                  matlab.ui.control.Label
        yEditField_2                matlab.ui.control.Spinner
        j4Label_29                  matlab.ui.control.Label
        xEditField_2                matlab.ui.control.Spinner
        j4Label_28                  matlab.ui.control.Label
        UR1_Lamp                    matlab.ui.control.Lamp
        UR0StatusPanel              matlab.ui.container.Panel
        GridLayout10_3              matlab.ui.container.GridLayout
        UR0EnableCheckBox           matlab.ui.control.CheckBox
        objectDropDown              matlab.ui.control.DropDown
        frameDropDown_3Label_4      matlab.ui.control.Label
        frameDropDown               matlab.ui.control.DropDown
        frameDropDown_3Label_3      matlab.ui.control.Label
        UR0modeDropDown             matlab.ui.control.DropDown
        StepEditField               matlab.ui.control.NumericEditField
        rzEditField                 matlab.ui.control.Spinner
        j4Label_27                  matlab.ui.control.Label
        ryEditField                 matlab.ui.control.Spinner
        j4Label_26                  matlab.ui.control.Label
        rxEditField                 matlab.ui.control.Spinner
        j4Label_25                  matlab.ui.control.Label
        zEditField                  matlab.ui.control.Spinner
        j4Label_24                  matlab.ui.control.Label
        yEditField                  matlab.ui.control.Spinner
        j4Label_23                  matlab.ui.control.Label
        xEditField                  matlab.ui.control.Spinner
        j4Label_22                  matlab.ui.control.Label
        j6EditField                 matlab.ui.control.Spinner
        j4Label_21                  matlab.ui.control.Label
        j5EditField                 matlab.ui.control.Spinner
        j4Label_20                  matlab.ui.control.Label
        j4EditField                 matlab.ui.control.Spinner
        j4Label_19                  matlab.ui.control.Label
        j3EditField                 matlab.ui.control.Spinner
        j3Label_3                   matlab.ui.control.Label
        j2EditField                 matlab.ui.control.Spinner
        j2Label_3                   matlab.ui.control.Label
        j1EditField                 matlab.ui.control.Spinner
        j1SpinnerLabel_3            matlab.ui.control.Label
        UR0_Lamp                    matlab.ui.control.Lamp
        OptitrackTab                matlab.ui.container.Tab
        GridLayout9                 matlab.ui.container.GridLayout
        Lamp                        matlab.ui.control.Lamp
        LampLabel                   matlab.ui.control.Label
        optiinitializeButton        matlab.ui.control.Button
        dispstartButton             matlab.ui.control.Button
        dispcloseButton             matlab.ui.control.Button
        SelectobjectDropDown        matlab.ui.control.DropDown
        SelectobjectDropDownLabel   matlab.ui.control.Label
        opticloseButton             matlab.ui.control.Button
        optistartButton             matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
        Button_5                    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = public)
        % 蓝牙相关
        % bluetooth_FA6F607D135E;
        %mag_data_queue % 存储磁场数据的循环队列 id Bx/y/z/T index
        mag_data_queue_id
        mag_data_queue_id_draw
        ur %  ur的控制句柄
        di % digraph句柄
    end

    properties (Access = private)
        bluetooth_F170A479AA6A;
        ble_connect_or_not = 0;
        c_read_ble;
        c_write_ble;
        % frame beginning and end detect
        frame_length = 15; %15 bytes
        beginning = 0x5E;
        ending = 0xFF;
        one_frame_newest;
        store_received_bytes = [];

        %%%
        Timer_id_2;
        %%%


        % %%%%%%%%
        RootFilename%数据文件根目录
        magSensorLogFilename%磁性传感器保存目录
        magSensorLogFilename_Continious%磁性传感器保存目录

        sampleFilename%样本保存目录
        paramFilename%参数保存目录
        optitrackLogFilename%optitrack数据保存目录
        URPoseLogFilename%UR位姿数据保存目录
        URPoseLogFilename_Continious%UR位姿数据保存目录

        sensor_amount %传感器总数，目前设置为9
        receive_serial % 接收串口句柄
        receive_buf %接收有效数据缓冲区
        buf_len_queue%记录缓冲区数据的队列
        text_serial %文字接收串口句柄


        residual_data %上一次未解析完的数据
        front %队列头指针
        rear  %队列尾指针
        text_buf
        text_front
        text_rear
        text_size
        queue_size %队列size


        %optitrack读取到的数据
        external_M = cell(1,3);     %index/position/orientation
        intrnal_m = cell(1,3);
        three_axis_sensor_temp = cell(1,3);
        temp_T_sensor  %最新的变换矩阵
        temp_T_Small_magnet
        temp_T_Big_Magnet
        tail_length = 20;
        %

        xkn
        Pkn
        R
        P_threshold
        Q% 1*9 cell
        kalman_reset_flag
        reset_count_011;
        sample_threshold
        sample_continuous_count

        sample_data % 存储样本数据的队列
        sample_append_flag
        sample_change_flag %判断样本数是否改变
        calib_config_file %校正参数保存路径
        cal_invA%校正参数
        cal_b%校正参数

        %选项卡相关参数
        tab_status
        %绘图相关参数
        an_x
        an_y
        an_z
        an_B
        an_last_head


        mag_dl
        mag_L
        mag_R
        mag_M


        Timer_id1 % Description
        Timer_id2
        Timer_id3
        Timer_id4
        Timer_id5
        Timer_id6  % 显示optitrack界面
        Timer_id7
        continuous_id % Description
        start_time %


        hemholts_index % 发送的数值的当前index
        max_hemholts_index % 当前turn最大发送数
        getB
        Bx_hemholts_current_maximum%当前最大值
        Bx_hemholts_current_circle
        Bx_hemholts % 向亥姆霍兹线圈发送的数值
        hemholts_repeat_circles % 重复的轮数
        Bx_hemholts_current_minimum%最小量
        data_sn % 每个数据文件存储的数据条数计数
        data_max_sn %每个数据文件存储的最大数据条数
        Btbl %存储数据的缓冲表 格式:序列号 id Bx By Bz T time 备注
        Btbl_temp %用于取得折线的缓冲表
        Sx %存储折线信息
        Sy
        Sz
        Btbl_temp_sn %临时表的序号

        Json_sn
        Json_max_sn
        Json

        clientSocket % 与Python通信的客户端
        Json_file_id % 存储json数据文件的文件id

        geomagnetism % 地磁

        obj % optitrack 的控制句柄


    end


    methods (Access = public)


        %%%%%%%%%%%
        %更新UR面板%
        %%%%%%%%%%%
        function update_UR_status_Panel(app,data_struct)
            %
            %data_struct结构：
            %             id:[1*1 double]
            %             mode:[1*1 double]
            %             pose: [1*6 double]
            %             deg:[1*6 double]
            %             enable:[1*1 logic]
            %             relative:[1*1 logic]

            switch data_struct.id
                case 0
                    %将其转换到标题所指向的坐标系上

                    %pose_base0 = data_struct.pose;
                    obj_frame = app.objectDropDown.Value;
                    dst_frame = app.frameDropDown.Value;
                    %pose_at_ref = app.di.get_pose_at(pose_base0,"base0",dst_frame)
                    pose_at_ref = app.di.get_frame_pose(obj_frame,dst_frame);
                    app.xEditField.Value = pose_at_ref(1);
                    app.yEditField.Value = pose_at_ref(2);
                    app.zEditField.Value = pose_at_ref(3);
                    app.rxEditField.Value = pose_at_ref(4);
                    app.ryEditField.Value = pose_at_ref(5);
                    app.rzEditField.Value = pose_at_ref(6);
                    app.j1EditField.Value = data_struct.deg(1);
                    app.j2EditField.Value = data_struct.deg(2);
                    app.j3EditField.Value = data_struct.deg(3);
                    app.j4EditField.Value = data_struct.deg(4);
                    app.j5EditField.Value = data_struct.deg(5);
                    app.j6EditField.Value = data_struct.deg(6);
                case 1
                    %pose_base1 = data_struct.pose;
                    obj_frame = app.objectDropDown_2.Value;
                    dst_frame = app.frameDropDown_2.Value;
                    %pose_at_ref = app.di.get_pose_at(pose_base1,"base1",dst_frame);
                    pose_at_ref = app.di.get_frame_pose(obj_frame,dst_frame);
                    app.xEditField_2.Value = pose_at_ref(1);
                    app.yEditField_2.Value = pose_at_ref(2);
                    app.zEditField_2.Value = pose_at_ref(3);
                    app.rxEditField_2.Value = pose_at_ref(4);
                    app.ryEditField_2.Value = pose_at_ref(5);
                    app.rzEditField_2.Value = pose_at_ref(6);
                    app.j1EditField_2.Value = data_struct.deg(1);
                    app.j2EditField_2.Value = data_struct.deg(2);
                    app.j3EditField_2.Value = data_struct.deg(3);
                    app.j4EditField_2.Value = data_struct.deg(4);
                    app.j5EditField_2.Value = data_struct.deg(5);
                    app.j6EditField_2.Value = data_struct.deg(6);
                case 2
                    obj_frame = app.objectDropDown_3.Value;
                    dst_frame = app.frameDropDown_3.Value;
                    %pose_at_ref = app.di.get_pose_at(pose_base1,"base1",dst_frame);
                    pose_at_ref = app.di.get_frame_pose(obj_frame,dst_frame);
                    app.xEditField_3.Value = pose_at_ref(1);
                    app.yEditField_3.Value = pose_at_ref(2);
                    app.zEditField_3.Value = pose_at_ref(3);
                    app.rxEditField_3.Value = pose_at_ref(4);
                    app.ryEditField_3.Value = pose_at_ref(5);
                    app.rzEditField_3.Value = pose_at_ref(6);
                    app.j1EditField_3.Value = data_struct.deg(1);
                    app.j2EditField_3.Value = data_struct.deg(2);
                    app.j3EditField_3.Value = data_struct.deg(3);
                    app.j4EditField_3.Value = data_struct.deg(4);
                    app.j5EditField_3.Value = data_struct.deg(5);
                    app.j6EditField_3.Value = data_struct.deg(6);

            end
        end
        %%%%%%%%%%%%%%%%%
        %模拟按下同步按钮%
        %%%%%%%%%%%%%%%%%
        function ClicksynclabelCheckBox(app)
            %模拟按下sync lable check box 功能
            app.synclabelCheckBox.Value = true;
            app.synclabelCheckBoxValueChanged([]);
        end
        %%%%%%%%%%%%%
        %显示接收数据%
        %%%%%%%%%%%%%
        function display_receive_data(app,B,receive_id,T)
            %修改显示面板的值
            if app.id_receive.Value == receive_id
                B_display = double(B);
                if app.autozeroCheckBox.Value
                    app.Bx_receive.Value = B_display(1)-app.geomagnetism(1,receive_id);
                    app.By_receive.Value = B_display(2)-app.geomagnetism(2,receive_id);
                    app.Bz_receive.Value = B_display(3)-app.geomagnetism(3,receive_id);
                else
                    app.Bx_receive.Value = B_display(1);
                    app.By_receive.Value = B_display(2);
                    app.Bz_receive.Value = B_display(3);
                end
                app.T_receive.Value = T;
                app.B_receive.Value = norm(B_display);
                if app.mag_data_queue_id{receive_id}.len>1
                    temp = app.mag_data_queue_id{receive_id}.get_last_N_data(2);
                    rate = temp(4,2)-temp(4,1);
                    app.RateEditField.Value = 1/rate;
                end
                
            end


        end
        %%%%%%%%%%%%%
        %修改采样数据%
        %%%%%%%%%%%%%
        function continuious_sample(app,B,receive_id)

            [~,samplesn] = size(app.sample_data{receive_id});
            if samplesn>0%若当前样本数不为0
                %根据样本队列的最后一个数据和当前数据作比较，判断是否为新样本
                last_sample = app.sample_data{receive_id}(:,end);
                threshold = 5;
                if norm(last_sample-B)<threshold
                    %非新样本，直接返回
                    return
                end
            end
            %判断数据是否进入收敛
            B_last = app.get_last_multiple_mag(receive_id,10);
            noise_xy = app.sample_threshold(receive_id);
            noise_z = app.sample_threshold(receive_id)*3;
            ax = ischange(B_last(:,1),'linear','Threshold',noise_xy);
            ay = ischange(B_last(:,2),'linear','Threshold',noise_xy);
            az = ischange(B_last(:,3),'linear','Threshold',noise_z);
            if(sum([ax,ay,az])>0)%不收敛，返回
                return
            else%收敛，将新数据直接加入目标队列
                app.sample_data{receive_id}(:,end+1)=B;
            end
        end

        function modify_samples(app,B,receive_id)
            %1-连续采集待矫正样本

            if app.ContinuousSaveCheckBox.Value == true

                app.continuious_sample(B,receive_id)
                app.sample_append_flag = 0;

            end

            %2-按键采集样本,取缓冲队列最后的数据加入sample_data
            if app.sample_append_flag>0

                for sample_id = 1:app.sensor_amount
                    sample_B = app.get_last_mag(sample_id);
                    app.sample_data{sample_id} = [app.sample_data{sample_id},sample_B];
                end
                app.sample_append_flag = app.sample_append_flag-1;
            end

            %3-删除多余样本
            if app.sample_append_flag<0

                n = abs(app.sample_append_flag);
                app.sample_append_flag = 0;
                for sample_id = 1:app.sensor_amount
                    [~,sample_size] = size(app.sample_data{sample_id});
                    if sample_size~=0
                        app.sample_data{sample_id}(:,sample_size-n+1:sample_size)=[];

                        app.SamplenumberEditField.Value = sample_size-n;
                    end

                end
            end

            %4-显示当前id对应的样本数量
            if receive_id == app.id_receive.Value
                [~,sample_size] = size(app.sample_data{receive_id});
                app.SamplenumberEditField.Value = sample_size;
            end
        end
        %%%%%%%%%%%%%
        %椭圆拟合    %
        %%%%%%%%%%%%%
        function [M,N,d] = ellipsoid_fit(~,s)

            % Paramenters:
            %-------------
            % s: 3*n double
            %    n is the number of samples
            %
            % Returns:
            %------------
            % M,N,d:3*3 double, 3*1 double, double
            %    The ellipsoid parameters M,N,d
            %
            % Reference:
            %------------
            % Qingde Li; Griffiths, J.G., "Least squares ellipsoid specific
            % fitting," in Geometric Modeling and Processing,2004.
            % Proceedings, vol., no., pp.335-340, 2004

            % D (samples)
            x = s(1,:);
            y = s(2,:);
            z = s(3,:);
            n = length(x);
            D = [x.*x       ;y.*y   ;   z.*z;...
                2.*y.*z     ;2.*x.*z;   2.*x.*y;...
                2.*x        ;2.*y   ;2.*z   ;   ones(1,n)];
            S = D*D';
            v = FindFit4(S);
            if v(1)<0
                v = -v;
            end
            M = [v(1),v(6),v(5);v(6),v(2),v(4);v(5),v(4),v(3)];

            N = reshape(v(7:9),[3,1]);
            d = v(10);
        end
        %%%%%%%%%%%%%
        %接收串口数据%
        %%%%%%%%%%%%%
        function ReadSerialData(app,s,~)
            %若接收区为0，返回接收
            if(s.NumBytesAvailable == 0)
                return
            end
            %将数据转换回u8格式
            data = uint8(read(app.receive_serial,s.NumBytesAvailable,"uint8"));
            %合并剩余数据和新接受到的数据
            data = [app.residual_data,data];
            app.residual_data = [];
            %初始化本次接收到的数据

            p = length(data);
            if p<15
                app.residual_data = data;
                return;
            end
            %判断帧头帧尾，是否为5e开头 ff结尾
            for i = 1:length(data)
                if i<p&&data(i) == hex2dec('5e')
                    if i+14<=p
                        if(data(i+14)) == hex2dec('ff')&&data(i+1)<app.sensor_amount
                            app.receive_buf.append(data(i:i+13));
                            i = i+15;
                        end
                    else
                        app.residual_data = data(i:end);

                    end

                end
                %                 if (i<p)&&((p-i)>=17)
                %
                %                     if data(i) == hex2dec('5e')&&data(i+18)==hex2dec('ff')
                %                         %检测到接收到了有效数据
                %                         receive_num = receive_num+1;
                %                         app.receive_buf(end+1) = {data(i:i+18)};
                %                         i = i+18;%跳循环至下一个可能的5e开头处
                %
                %                     end
                %                 else
                %                     %disp(ME);
                %                         app.residual_data = data(i:end);
                %                         app.resbyteEditField.Value = length(app.residual_data);
                %                         break;
                %                 end
            end

            %将本次待处理数据填充入txt中
            app.text_filling(data);

            if app.Timer_id3.Running == "off"
                start(app.Timer_id3);
            end
            %start(ap.Timer_id1);
        end


        %% 定时器初始化
        function timer_init(app)
            app.Timer_id1 = timer;
            app.Timer_id1.StartDelay = 0.1;
            app.Timer_id1.Period = 0.1;
            app.Timer_id1.ExecutionMode = 'fixedSpacing';
            app.Timer_id1.TimerFcn = @(x,y)app.draw_figure;
            app.Timer_id1.BusyMode = 'drop';

            app.Timer_id2 = timer;
            app.Timer_id2.StartDelay = 0.01;
            app.Timer_id2.Period = 0.1;
            app.Timer_id2.ExecutionMode = 'fixedDelay';
            app.Timer_id2.TimerFcn = @(x,y)app.timer_handler2;

            app.Timer_id3 = timer;
            app.Timer_id3.StartDelay = 0.1;
            app.Timer_id3.Period = 0.001;
            app.Timer_id3.ExecutionMode = 'fixedSpacing';
            app.Timer_id3.TimerFcn = @(x,y)app.process_data;
            app.Timer_id3.BusyMode = 'drop';

            % app.Timer_id4 = timer;
            % app.Timer_id4.StartDelay = 1;
            % app.Timer_id4.Period = 1;
            % app.Timer_id4.ExecutionMode = "fixedDelay";
            % app.Timer_id4.TimerFcn = {@app.transmit_hemholts,"hemholts start"};
            % app.Timer_id4.BusyMode = 'drop';

            app.Timer_id4 = timer;
            app.Timer_id4.StartDelay = 1;
            app.Timer_id4.Period = 4;
            app.Timer_id4.ExecutionMode = "fixedDelay";
            app.Timer_id4.TimerFcn = @(x,y)app.URContinuousExecute;
            app.Timer_id4.BusyMode = 'queue';

            app.Timer_id5 = timer;
            app.Timer_id5.StartDelay = 0;
            app.Timer_id5.Period = 0.2;
            app.Timer_id5.ExecutionMode = "fixedSpacing";
            app.Timer_id5.TimerFcn =@(x,y)app.optitrack_read;
            app.Timer_id5.BusyMode = 'drop';

            app.Timer_id6 = timer;
            app.Timer_id6.StartDelay = 0;
            app.Timer_id6.Period = 0.1;
            app.Timer_id6.ExecutionMode = "fixedSpacing";
            app.Timer_id6.TimerFcn =@(x,y)app.optitrack_show;
            app.Timer_id6.BusyMode = 'drop';

            app.Timer_id7 = timer;
            app.Timer_id7.StartDelay = 5;
            app.Timer_id7.Period = 500;
            app.Timer_id7.ExecutionMode = "singleShot";
            app.Timer_id7.TimerFcn =@(x,y)app.cancle_sync_checkbox;
            app.Timer_id7.BusyMode = 'drop';


        end

        function timer_init_ble(app) %蓝牙定时器初始化
            % timer2
            app.Timer_id_2 = timer;
            app.Timer_id_2.StartDelay = 0.001; % 开启的延时生效时间
            app.Timer_id_2.Period = 0.05;% 周期
            app.Timer_id_2.ExecutionMode = 'fixedSpacing';
            app.Timer_id_2.TimerFcn = @(~, ~) process_data_ble(app);

        end
        %%%%%%%%%%%%%%%%%%%%%%
        %UR连续执行任务%
        %%%%%%%%%%%%%%%%%%%%%%
        function URContinuousExecute(app)
            persistent is_triggered;
            if isempty(is_triggered)
                is_triggered = true;
            end
            %若有停止键按下，停止定时器并返回
            if app.URStopStatusButton.Value
                app.URStopStatusButton.Value = false;
                app.URAutoNextButton.Value = false;
                app.URAutoLastButton.Value = false;
                is_triggered = true;
                stop(app.Timer_id4);

                return;
            end

            %确认机械臂状态
            app.URSyncButtonPushed([]);
            % ur0_busy = isequal(app.UR0_Lamp.Color,[1,0,0]);
            % ur1_busy = isequal(app.UR1_Lamp.Color,[1,0,0]);
            % ur0_enable =app.UR0EnableCheckBox.Value;
            % ur1_enable =app.UR1EnableCheckBox.Value;

            %当ur0使能且ur0为忙 ur1使能且ur1为忙 ur0 ur1均不使能时，退出此轮定时器
            % if (ur0_enable&&ur0_busy)||(ur1_enable&&ur1_busy)||(~ur0_enable&&~ur1_enable)
            % 
            %     return;
            % end


            %若synclabelCheckBox为true,退出此轮定时器
            if app.synclabelCheckBox.Value

                return;
            end


            %若机械臂尚未触发自动记录，触发自动记录,退出此轮定时器
            % if ~is_triggered
            %     is_triggered = true;
            %     % app.ClicksynclabelCheckBox();
            % 
            %     return;
            % end

            index = app.URCommandIndexSpinner.Value;


            %若命令队列的待执行命令大于最大长度，停止定时器、更新按钮状态、返回
            if index>app.ur.URMsgQueue.len
                app.URAutoNextButton.Value = false;
                stop(app.Timer_id4);

                return
            end
            %若命令队列的待执行命令小于0，停止定时器、更新按钮状态、返回
            if index<1
                app.URAutoLastButton.Value = false;
                stop(app.Timer_id4);

                return
            end

            %获取命令队列的命令

            assignin("base","ur",app.ur);
            % ur_std_msg = app.ur.URMsgQueue.index(index);
            %获取待执行命令的remark，同步更新到syncEditField中
            %remark = ur_std_msg.remark;
            % if ~isequal(remark,"")%若remark为空，不更新sync
            %     a = split(remark,":");
            %     app.labelTextArea.Value = a(1);
            %     app.syncEditField.Value = str2double(a(2));
            % end

            app.URExecuteButtonButtonPushed([]);


            is_triggered = false;
            %根据向上还是向下更新URCommandIndexSpinner
            if app.URAutoNextButton.Value %向下更新
                app.URCommandIndexSpinner.Value = app.URCommandIndexSpinner.Value +1;
            else
                app.URCommandIndexSpinner.Value = app.URCommandIndexSpinner.Value -1;
            end

        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %sync停止自动保存定时器回调函数%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function cancle_sync_checkbox(app)
            app.synclabelCheckBox.Value = false;
            app.SaveURPoseButtonPushed([]);
            app.syncEditField.Value = app.syncEditField.Value +1;
            stop(app.Timer_id7);
        end
        %%%%%%%%%%%%%%%%%%%%%%
        %数据处理及显示回调函数%
        %%%%%%%%%%%%%%%%%%%%%%

        %% optitrack 各种位姿的处理 定时器回调
        function optitrack_read(app)
            %只在定时器打开时起作用
            rb = app.obj.RigidBody;
            % FrameIndex = rb(1).FrameIndex;    %帧编号      %index/position/orientation
            a = 0;
            % app.external_M{1,1} = [app.external_M{1,1} ; FrameIndex];
            % app.intrnal_m{1,1} = [app.intrnal_m{1} ; FrameIndex];
            % app.three_axis_sensor_temp{1,1} = [app.three_axis_sensor_temp{1} ; FrameIndex];
            time = seconds(datetime("now")-app.start_time);
            if(app.synclabelCheckBox.Value)

                remark_text  = string(app.labelTextArea.Value)+":"+num2str(app.syncEditField.Value,"%04d");
            else
                remark_text  = "";
            end
            % 遍历所有刚体
            for i = 1:numel(rb)
                % fprintf('- %s, Tracking Status: %d\n',rb(i).Name,rb(i).isTracked);
                if rb(i).isTracked
                    % 组成变换矩阵%

                    if rb(i).Name == "Sensor_part"
                        % T_sensor_rigid = [rb(i).Rotation, rb(i).Position; 0 0 0 1];
                        a = a+1;
                        T_sensor_rigid = rb(i).HgTransform;

                        app.temp_T_sensor = T_sensor(T_sensor_rigid, app.id_receive.Value) ; % 取用传感器位姿，序号与面板保持一致
                        temp_u_sensor = T_sensor_rigid(1:3, 4);
                        temp_R_sensor = rb(i).Rotation;
                        %temp_quat_sensor = rb(i).Quaternion;
                        temp_lie_sensor = Log(temp_R_sensor);

                        app.three_axis_sensor_temp{1,1} = [app.three_axis_sensor_temp{1,1} ; rb(i).FrameIndex];
                        app.three_axis_sensor_temp{1,2} = [app.three_axis_sensor_temp{1,2} ; temp_u_sensor'];
                        app.three_axis_sensor_temp{1,3} = [app.three_axis_sensor_temp{1,3} ; temp_lie_sensor];
                        if (length(app.three_axis_sensor_temp))>100
                            app.three_axis_sensor_temp = [];
                        end
                        tbl = {rb(i).FrameIndex,"Sensor_part",temp_u_sensor(1),temp_u_sensor(2),temp_u_sensor(3),...
                            temp_lie_sensor(1),temp_lie_sensor(2),temp_lie_sensor(3),time,remark_text};
                        app.logOptitrackData(tbl);

                    elseif rb(i).Name == "Small_magnet"
                        a = a+1;
                        T_Small_magnet_rigid = rb(i).HgTransform;
                        app.temp_T_Small_magnet = T_small_magnet(T_Small_magnet_rigid, [0,0,0]') ;
                        temp_P_small_magnet = T_Small_magnet_rigid(1:3, 4);
                        temp_R_small_magnet = rb(i).Rotation;
                        temp_lie_small_magnet= Log(temp_R_small_magnet);

                        app.intrnal_m{1,1} = [app.intrnal_m{1,1} ; rb(i).FrameIndex];
                        app.intrnal_m{1,2} = [app.intrnal_m{1,2} ; temp_P_small_magnet'];
                        app.intrnal_m{1,3} = [app.intrnal_m{1,3} ; temp_lie_small_magnet];
                        if (length(app.intrnal_m))>100
                            app.intrnal_m = [];
                        end
                        tbl = {rb(i).FrameIndex,"Small_magnet",temp_P_small_magnet(1),temp_P_small_magnet(2),temp_P_small_magnet(3),...
                            temp_lie_small_magnet(1),temp_lie_small_magnet(2),temp_lie_small_magnet(3),time,remark_text};
                        app.logOptitrackData(tbl);

                    elseif rb(i).Name == "Big_magnet"
                        a = a+1;
                        T_Big_magnet_rigid = rb(i).HgTransform;
                        app.temp_T_Big_Magnet = T_big_magnet(T_Big_magnet_rigid) ;% 取用小磁铁的位姿 【0 0 0】是后续需要确定的 【也可以有多个】
                        temp_P_big_magnet = T_Big_magnet_rigid(1:3, 4);
                        temp_R_big_magnet =  rb(i).Rotation;
                        temp_lie_big_magnet= Log(temp_R_big_magnet);

                        app.external_M{1,1} = [app.external_M{1,1} ; rb(i).FrameIndex];
                        app.external_M{1,2} = [app.external_M{1,2} ; temp_P_big_magnet'];
                        app.external_M{1,3} = [app.external_M{1,3} ; temp_lie_big_magnet];
                        if (length(app.external_M))>100
                            app.external_M = [];
                        end
                        tbl = {rb(i).FrameIndex,"Big_magnet",temp_P_big_magnet(1),temp_P_big_magnet(2),temp_P_big_magnet(3),...
                            temp_lie_big_magnet(1),temp_lie_big_magnet(2),temp_lie_big_magnet(3),time,remark_text};
                        app.logOptitrackData(tbl);
                    end

                else
                    % fprintf('\t Position []\n');
                    % fprintf('\t Quaternion []\n');
                end
            end
            if(a == 0)
                app.Lamp.Color = [1.00,0.00,0.00];
            elseif(a == 1)
                app.Lamp.Color =[0.00,0.00,1.00];
            elseif(a==2)
                app.Lamp.Color = [1.00,1.00,0.07];
            elseif(a==3)
                app.Lamp.Color = [0.39,0.83,0.07];
            end
        end
        %% optitrack 显示回调函数
        function optitrack_show(app)
            if app.SelectobjectDropDown.Value == "sensor1"
                if length(app.three_axis_sensor_temp{1,1}) > app.tail_length
                    temp = [{app.three_axis_sensor_temp{1,1}(end)}, {mat2str(app.three_axis_sensor_temp{1,2}(end,:), 3)}, {mat2str(app.three_axis_sensor_temp{1,3}(end,:), 3)}];
                    set(app.UITable, 'Data', [temp; app.UITable.Data])
                    plot3(app.UIAxes, app.three_axis_sensor_temp{1,2}(end-app.tail_length: end,1), app.three_axis_sensor_temp{1,2}(end-app.tail_length: end,2), app.three_axis_sensor_temp{1,2}(end-app.tail_length: end,3));
                    hold(app.UIAxes,"on");
                    scatter3(app.UIAxes, app.three_axis_sensor_temp{1,2}(end,1), app.three_axis_sensor_temp{1,2}(end,2), app.three_axis_sensor_temp{1,2}(end,3));
                    app.draw_frame(app.UIAxes, app.temp_T_sensor, 1)
                end
            elseif app.SelectobjectDropDown.Value == "internal m"
                if length(app.intrnal_m{1,1}) > app.tail_length
                    temp = [{app.intrnal_m{1,1}(end)}, {mat2str(app.intrnal_m{1,2}(end,:), 3)}, {mat2str(app.intrnal_m{1,3}(end,:), 3)}];
                    set(app.UITable, 'Data', [temp; app.UITable.Data])
                    plot3(app.UIAxes, app.intrnal_m{1,2}(end-app.tail_length: end,1), app.intrnal_m{1,2}(end-app.tail_length: end,2), app.intrnal_m{1,2}(end-app.tail_length: end,3));
                    hold(app.UIAxes,"on");
                    scatter3(app.UIAxes, app.intrnal_m{1,2}(end,1), app.intrnal_m{1,2}(end,2), app.intrnal_m{1,2}(end,3));
                    app.draw_frame(app.UIAxes, app.temp_T_Small_magnet, 1)
                end
            elseif app.SelectobjectDropDown.Value == "external M"
                if length(app.external_M{1,1}) > app.tail_length
                    temp = [{app.external_M{1,1}(end)}, {mat2str(app.external_M{1,2}(end,:), 3)}, {mat2str(app.external_M{1,3}(end,:), 3)}];
                    set(app.UITable, 'Data', [temp; app.UITable.Data])
                    plot3(app.UIAxes, app.external_M{1,2}(end-app.tail_length: end,1), app.external_M{1,2}(end-app.tail_length: end,2), app.external_M{1,2}(end-app.tail_length: end,3));
                    hold(app.UIAxes,"on");
                    scatter3(app.UIAxes, app.external_M{1,2}(end,1), app.external_M{1,2}(end,2), app.external_M{1,2}(end,3));
                    app.draw_frame(app.UIAxes, app.temp_T_Big_Magnet, 1)
                end
            end

            if length(app.UITable.Data)>20
                app.UITable.Data = [];
            end

        end

        %% 绘制小坐标系
        function draw_frame(~, fh, A, alpha)
            %fh:绘图句柄 A:平移量 alpha:透明度
            len = 10;
            lw = 3;
            new_red = [238, 0, 0]/255;
            new_green = [0, 238, 0]/255;
            new_blue = [0, 0, 238]/255;
            cod = [len .* eye(3); 1, 1, 1];
            org = A * [0; 0; 0; 1];
            cod = A * cod;
            plot3(fh, [org(1), cod(1, 1)], [org(2), cod(2, 1)], [org(3), cod(3, 1)], ...
                '-', 'LineWidth', lw, 'Color', [new_red, alpha]);
            plot3(fh, [org(1), cod(1, 2)], [org(2), cod(2, 2)], [org(3), cod(3, 2)], ...
                '-', 'LineWidth', lw, 'Color', [new_green, alpha]);
            plot3(fh, [org(1), cod(1, 3)], [org(2), cod(2, 3)], [org(3), cod(3, 3)], ...
                '-', 'LineWidth', lw, 'Color', [new_blue, alpha])
            hold(fh,"off");
        end

        %%
        function process_data(app)
        %while 循环以及下面循环中全部的注释是为了解决向下回放卡住的问题，如果发生其他问题先改这里   
            while(1)
                if(app.receive_buf.len==0)
                     break;
                else
                    data = app.receive_buf.pop_head();

                    if isempty(data)
                        continue
                    end
                   
                    [B,receive_id,T] = app.decode(data);
      
                    app.modify_samples(B,receive_id);
                    
                    app.display_receive_data(B,receive_id,T);
                    % disp("cnm")
                end
            end
            %解析折线信息
               % disp("nmmsl")
            if(app.Btbl_temp_sn>1)
                % [app.Sx,app.Sy,app.Sz] = get_polyline(app.Btbl_temp(1:app.Btbl_temp_sn-1,:),app.Sx,app.Sy,app.Sz,1);%目前只关注1传感器
                app.Btbl_temp_sn = 1;
            end

        end
        %%%%%%%%%%%%%
        %画图回调函数%
        %%%%%%%%%%%%%
        function draw_figure(app)
            %执行定时器任务，主要是画图
            if app.LineCheckBox.Value&& app.mag_data_queue_id_draw.len~=0
                id = app.id_receive.Value;
                if(id>app.sensor_amount||id<1)
                    return
                end
                % mag_data_queue_draw = app.mag_data_queue(:,:,:);
                % draw_front = app.an_last_head(id);
                % draw_rear = app.rear(id);
                % 
                % 
                % if draw_front<draw_rear
                %     index = draw_front:draw_rear-1;
                % elseif draw_front>draw_rear
                %     index = [draw_front:app.queue_size,1:draw_rear-1];
                % end
                len = app.mag_data_queue_id_draw.len;
                data = app.mag_data_queue_id_draw.get_last_N_data(len);
                %app.an_last_head(id) = app.rear(id);
                %t = mag_data_queue_draw(id,4,index);
                app.mag_data_queue_id_draw.clear;
                % t_vec = reshape(t,1,length(t));

                t_vec = data(4,:);
                Bx_vec = data(1,:);
                By_vec = data(2,:);
                Bz_vec = data(3,:);
                B_vec = sqrt(Bx_vec.^2+By_vec.^2+Bz_vec.^2);
                % Bx = mag_data_queue_draw(id,1,index);
                % Bx_vec = reshape(Bx,1,length(Bx));
                % By = mag_data_queue_draw(id,2,index);
                % By_vec = reshape(By,1,length(By));
                % Bz = mag_data_queue_draw(id,3,index);
                % Bz_vec = reshape(Bz,1,length(Bz));
                %B = sqrt(Bx.^2+By.^2+Bz.^2);
                %B_vec = reshape(B,1,length(B));
                addpoints(app.an_x,t_vec,Bx_vec);
                addpoints(app.an_y,t_vec,By_vec);
                addpoints(app.an_z,t_vec,Bz_vec);
                addpoints(app.an_B,t_vec,B_vec)

                    %legends = {'Bx','By','Bz'};
                    %legend(app.LineAxes,legends);
                    %axis(app.LineAxes,[t(1),t(end),min(B),max(B)])
                    %drawnow limitrate

            end
            if app.polyLineCheckBox.Value
                % lim = 5000;
                % hold(app.SxAxes,"off")
                % plot(app.SxAxes,[-lim, -lim, lim, -lim], [-lim, lim, lim, -lim], '-')
                % % The Sx pair lies on the upper left corner.
                % S = app.Sx;
                %
                %  S(1, :) = [lim, -lim];
                % % % Now S(:, 1) is alpha and S(:, 2) is beta. Show them in the triangle.
                % hold(app.SxAxes,"on")
                % plot(app.SxAxes,S(:, 2), S(:, 1));
                %
                %  hold(app.SyAxes,"off")
                % plot(app.SyAxes,[-lim, -lim, lim, -lim], [-lim, lim, lim, -lim], '-')
                % % The Sy pair lies on the upper right corner.
                %
                % S = app.Sy;
                %  S(1, :) = [lim, -lim];
                % % % Now S(:, 1) is alpha and S(:, 2) is beta. Show them in the triangle.
                % hold(app.SyAxes,"on")
                % plot(app.SyAxes,S(:, 2), S(:, 1));
                %
                %
                % hold(app.SzAxes,"off")
                % plot(app.SzAxes,[-lim, -lim, lim, -lim], [-lim, lim, lim, -lim], '-')
                % % The first pair lies on the upper left corner.
                % S = app.Sz;
                %
                %  S(1, :) = [lim, -lim];
                % % % Now S(:, 1) is alpha and S(:, 2) is beta. Show them in the triangle.
                % hold(app.SzAxes,"on")
                % plot(app.SzAxes,S(:, 2), S(:, 1));

            end

            if app.scatterCheckBox.Value&&app.SamplenumberEditField.Value>0
                grid(app.ScatterAxes,"on");
                %                     addpoints(app.an_x,...
                %                         app.sample_data{app.id_receive.Value}(1,app.an_last_head:end),...
                %                         app.sample_data{app.id_receive.Value}(2,app.an_last_head:end),...
                %                         app.sample_data{app.id_receive.Value}(3,app.an_last_head:end));
                %                     app.an_last_head = length(app.sample_data{app.id_receive.Value}(3,:));
                s = [0,0,1];
                m = length(app.sample_data{app.id_receive.Value}(1,:));
                C = repmat(s,[m-1,1]);
                C(m,:) = [1,0,0];
                scatter3(app.ScatterAxes,app.sample_data{app.id_receive.Value}(1,:),app.sample_data{app.id_receive.Value}(2,:),app.sample_data{app.id_receive.Value}(3,:),[],C);
                hAxis = app.ScatterAxes;
                hAxis.XRuler.FirstCrossoverValue  = 0; % X crossover with Y axis
                hAxis.YRuler.FirstCrossoverValue  = 0; % Y crossover with X axis
                hAxis.ZRuler.FirstCrossoverValue  = 0; % Z crossover with X axis
                hAxis.ZRuler.SecondCrossoverValue = 0; % Z crossover with Y axis
                hAxis.XRuler.SecondCrossoverValue = 0; % X crossover with Z axis
                hAxis.YRuler.SecondCrossoverValue = 0; % Y crossover with Z axis

                drawnow limitrate
                app.sample_change_flag = false;
            end


        end

        %%%%%%%%%%%%%
        %将接收到的数据转化为字符%
        %%%%%%%%%%%%%
        function text_filling(app,data)
            app.text_receive.Value = join(string(dec2hex(data)));
            scroll(app.text_receive,"bottom");
        end

        %%%%%%%%%%%%%
        %将接收到的数据解码%
        %%%%%%%%%%%%%
        function [B,id_value,T_value,time]=decode(app,data)
            persistent count
            if (isempty(count))
                count = 0;
            end

            persistent sync_discard_index;
            if isempty(sync_discard_index)
                sync_discard_index = 0;
            end
            id = data(2)+1;

            if id>app.sensor_amount
                disp(dec2hex(data));
            end
            %解码记录到的数据

            Bx_value = double(typecast(data(3:6),"single"));

            By_value = double(typecast(data(7:10),"single"));

            Bz_value = double(typecast(data(11:14),"single"));

            %T_value = double(typecast(data(15:18),"single"));
            T_value = 0.0;

            id_value = double(id);
            time = seconds(datetime("now")-app.start_time);

            if abs(Bx_value)>1e5 || abs(By_value)>1e5 || abs(Bz_value)>1e5 || isnan(Bx_value) || isnan(By_value) || isnan(Bz_value)
                B = [0,0,0]';
                return
            end

            if(mod(app.rear(id),app.queue_size)+1)==app.front(id)%队满头出列
                app.front = mod(app.front,app.queue_size)+1;

            end

            B = [Bx_value,By_value,Bz_value]';
            %当且仅当synclabelCheckBox为true时记录Btbl，存放至app.Btbl队列中
            if app.synclabelCheckBox.Value
                sync_discard_index = sync_discard_index+1;
                if sync_discard_index > 96
                    label_text =string(app.labelTextArea.Value)+":"+num2str(app.syncEditField.Value,"%04d");
                    newtbl = {int8(id_value),Bx_value,By_value,Bz_value,T_value,time,label_text};
                    app.logMagSensorData(newtbl);
                end
            else
                sync_discard_index = 0;
            end
            
            %判断是否开启了校正
            % if app.justbiasCheckBox.Value == true
            %     b = app.cal_b{id}{1};
            %     B = reshape(B,[3,1]);
            %     B_new = B-b;
            % elseif app.calibrationCheckBox.Value == true
            %     invA = app.cal_invA{id};
            %     b = app.cal_b{id};
            % 
            %     B = reshape(B,[3,1]);
            %     B_new = invA*(B-b);
            % 
            % else
            %     B_new = B;
            % end
            % 
            % %卡尔曼滤波
            % if app.kalmanCheckBox.Value == true
            % 
            %     B_new = app.Kalman(B_new,id);
            % else
            %     app.xkn{id} = B_new;
            % 
            % end
            % B = B_new;

            %根据id将数据填入指定缓冲区

            app.mag_data_queue_id{id}.append([reshape(B,[3,1]);time]);
            if id == app.id_receive.Value
                app.mag_data_queue_id_draw.append([reshape(B,[3,1]);time]);
            end
            assignin("base","mag_data_queue",app.mag_data_queue_id);
            % app.mag_data_queue(id,1,app.rear(id)) = B(1);
            % app.mag_data_queue(id,2,app.rear(id)) = B(2);
            % app.mag_data_queue(id,3,app.rear(id)) = B(3);
            % app.mag_data_queue(id,4,app.rear(id)) = time;
            % if id == app.id_receive.Value
            %     last_rear = app.rear(id)-1;
            %     if last_rear == 0
            %         last_rear = app.queue_size;
            %     end
            %     app.RateEditField.Value = 1/(time-app.mag_data_queue(id,4,last_rear));
            % end
            % app.rear(id) = mod(app.rear(id),app.queue_size)+1;
            % assignin("base","mag_data_queue",app.mag_data_queue);
            time_this_turn = time;

            if app.Continious_saveCheckBox.Value
                last_data = app.mag_data_queue_id{count+1}.get_last_N_data(1);
                newtbl = {count,last_data(1),last_data(2),last_data(3),0,time_this_turn,"Continious"};
                app.logMagSensorData_Continious(newtbl);
                
                if app.URSyncButton.Enable == "on" && count == app.sensor_amount-1
                    
                    app.URSyncButtonPushed([]);
                    
                    label_text ="Continious";
                    %大磁铁位置
                    frame_name = "BigMagnetF";
                    pose = app.di.get_frame_pose(frame_name,"base1");
                    newtbl = {frame_name,pose(1),pose(2),pose(3),pose(4),pose(5),pose(6),time_this_turn,label_text};
                    app.logURPoseData_Continious(newtbl);

                    %小磁铁位置
                    frame_name = "SmallMagnet";
                    pose = app.di.get_frame_pose(frame_name,"base1");
                    newtbl = {frame_name,pose(1),pose(2),pose(3),pose(4),pose(5),pose(6),time_this_turn,label_text};
                    app.logURPoseData_Continious(newtbl);

                    %tcp位置
                    frame_name = "ur1Tcp";
                    pose = app.di.get_frame_pose(frame_name,"base1");
                    newtbl = {frame_name,pose(1),pose(2),pose(3),pose(4),pose(5),pose(6),time_this_turn,label_text};
                    app.logURPoseData_Continious(newtbl);

                    frame_name = "ur2Tcp";
                    pose = app.di.get_frame_pose(frame_name,"base1");
                    newtbl = {frame_name,pose(1),pose(2),pose(3),pose(4),pose(5),pose(6),time_this_turn,label_text};
                    app.logURPoseData_Continious(newtbl);
                    
                    %传感器位置
                    for id = 0:app.sensor_amount- 1
                        frame_name = "id"+id;
                        pose = app.di.get_frame_pose(frame_name,"base1");
                        newtbl = {frame_name,pose(1),pose(2),pose(3),pose(4),pose(5),pose(6),time_this_turn,label_text};
                        app.logURPoseData_Continious(newtbl);
                    end
                % 
                end

                if (count < app.sensor_amount-1)
                    count = count+1;
                else
                    count = 0;
                end
            end
        end

        function B_Kalman = Kalman(app,Bnew,id)
            %判断是否进行卡尔曼滤波
            last_rear = app.rear(id);
            p_num = 5;
            B_std = zeros(p_num,3);
            for k = 1:p_num
                last_rear = last_rear-1;
                if(last_rear == 0)
                    last_rear = app.queue_size;
                end
                %B_std(k,:) = [app.mag_data_queue(id,1,last_rear),app.mag_data_queue(id,2,last_rear),app.mag_data_queue(id,3,last_rear)]';
                B_std(k,:) = app.get_last_mag(id);
            end
            B_std(end,:) = reshape(Bnew,[1,3]);
            %Pkx = std(B_std(end-1:end,1));
            %Pky = std(B_std(end-1:end,2));
            %Pkz = std(B_std(end-1:end,3));
            id_011 = [2,8,9];

            if(~ismember(id,id_011))

                %noise_xy = 1.2;
                %noise_z = 3;
                coff =app.P_threshold{id};

                noise_xy = coff*1.2;
                noise_z = coff*3;
                ax = ischange(B_std(:,1),'linear','Threshold',noise_xy);
                ay = ischange(B_std(:,2),'linear','Threshold',noise_xy);
                az = ischange(B_std(:,3),'linear','Threshold',noise_z);
                ax = ax(end-1);
                ay = ay(end-1);
                az = az(end-1);
                reset_flag = (ax&ay)|(ax&az)|(ay&az);
                if(reset_flag)
                    app.kalman_reset_flag(id) = true;
                    app.reset_count_011 =  app.reset_count_011+1;
                    app.reset_count_011 = min(app.reset_count_011,20);
                else
                    app.reset_count_011 = max(app.reset_count_011-1,0);
                end
                if app.reset_count_011>2
                    app.kalman_reset_flag(id_011)=app.kalman_reset_flag(id_011)|true;
                end
            end

            coff = 20.015;
            R = app.R{id};
            if(~app.kalman_reset_flag(id))
                Zk = Bnew;
                Pkn = app.Pkn{id}+eye(3)*app.Q;
                xkn = app.xkn{id};
                Kk = Pkn*inv(Pkn+R);
                xk = xkn + Kk*(Zk-xkn);
                Pk = (eye(3)-Kk)*Pkn;
                app.xkn{id} = xk;
                app.Pkn{id} = Pk;
                B_Kalman = xk;
            else %011跟着010重启
                app.kalman_reset_flag(id) = false;
                app.xkn{id} = Bnew;
                Pk = diag([coff*R(1,1),coff*R(2,2),coff*R(3,3)]);
                app.Pkn{id} = Pk;
                B_Kalman = Bnew;

            end

        end
    end


    methods (Access = private)


        function B_new = wash_data(~,B,F)
            B_norm = sqrt(diag(B'*B));
            data = B_norm;
            best_k = 0;
            max_silhouette_avg = -Inf;
            for k = 2:10
                [cluster_idx,~] = kmeans(data,k);
                silhouette_vals = silhouette(data,cluster_idx);
                silhouette_avg = mean(silhouette_vals);

                if silhouette_avg>max_silhouette_avg
                    max_silhouette_avg = silhouette_avg;
                    best_k = k;
                end
            end
            [index,C] = kmeans(data,best_k);
            meanC = mean(C);
            if (C-meanC)<300
                B_new = B;
            else
                [~,used_index] = min(abs(F-C));
                B_new = B(:,index == used_index);
            end
        end


        function [uM,thetaM,Bs] = resolve_table(app)
            t = app.CalibParaTable.Data;
            if isempty(t)
                Bs = [];
                uM = [];
                thetaM = [];
            else
                m = height(t);
                uM = cell(m,1);
                thetaM = cell(m,1);
                Bs = cell(m,1);
                for i = 1:m
                    uM{i}=reshape(t(i,:).uM,[3,1]);
                    thetaM{i}=reshape(t(i,:).thetaM,[3,1]);
                    Bs{i}=t(i,:).B(4);
                end
            end
        end

        function B = get_last_mag(app,id)
            if app.mag_data_queue_id{id}.len ==0
                B = [0,0,0]';
                return
            end
            data = app.mag_data_queue_id{id}.get_last_N_data(1);
            B = data(1:3);
            % last = app.rear(id)-1;
            % if last==0
            %     last = app.queue_size;
            % end
            % B = app.mag_data_queue(id,1:3,last);
            % B = reshape(B,[3,1]);

        end

        function B = get_last_multiple_mag(app,id,n)
            assert(n>0);
            assert(n<app.queue_size);
            if app.mag_data_queue_id{id}.len ==0
                B = zeros(3,n);
                return
            end
            temp = app.mag_data_queue_id{id}.get_lastN_data(n);
            B = temp(1:3,:);
            % B = zeros(3,n);
            % for i = 1:n
            %     last = app.rear(id)-1;
            %     if last==0
            %         last = app.queue_size;
            %     end
            %     B_temp = app.mag_data_queue(id,1:3,last);
            %     B(:,i) = reshape(B_temp,[3,1]);
            % end

        end

        function B = get_last_avg_mag(app,id,n)
            B_multiple = app.get_last_multiple_mag(id,n);
            B = mean(B_multiple,2);
        end

        function logURPoseData(app,newtbl,filename)
            persistent Utbl;
            persistent sn_U;
            if(isempty(newtbl))
                app.logURPoseDataToFile(Utbl,filename);
                Utbl = [];
                sn_U = 0;
                return;
            end
            if(isempty(Utbl))
                varTypes = ["int32","string","double","double","double","double","double","double","double","string"];
                varNames = ["SN","FrameName","x","y","z","ox","oy","oz","timestamp","remark"];
                sz = [app.data_max_sn,10];
                Utbl = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                sn_U = 1;
                Utbl(1,:) = [sn_U,newtbl];
            else
                sn_U = sn_U+1;
                Utbl(sn_U,:) = [sn_U,newtbl];
            end
            if sn_U>app.data_max_sn
                app.logURPoseDataToFile(Utbl,filename);
                sn_U = 0;
                Utbl = [];
            end
        end

        function logURPoseData_Continious(app,newtbl)
            persistent Utbl_C;
            persistent sn_UC;
            if(isempty(newtbl))
                app.logURPoseDataToFile(Utbl_C,app.URPoseLogFilename_Continious);
                Utbl_C = [];
                sn_UC = 0;
                return;
            end
            if(isempty(Utbl_C))
                varTypes = ["int32","string","double","double","double","double","double","double","double","string"];
                varNames = ["SN","FrameName","x","y","z","ox","oy","oz","timestamp","remark"];
                sz = [app.data_max_sn,10];
                Utbl_C = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                sn_UC = 1;
                Utbl_C(1,:) = [1,newtbl];
            else
                sn_UC = sn_UC+1;
                Utbl_C(sn_UC,:) = [sn_UC,newtbl];
            end
            if sn_UC>app.data_max_sn
                app.logURPoseDataToFile(Utbl_C,app.URPoseLogFilename_Continious);
                sn_UC = 0;
                Utbl_C = [];
            end
        end

        function logURPoseDataToFile(app,tbl,filename)
            persistent file_id;
            if(isempty(file_id))
                file_id = 0;
            end
            if(~isempty(tbl))
                tbl(:,1) = file_id*10000+tbl(:,1);
                logname = "\"+num2str(file_id,"%06d")+".parquet";
                parquetwrite(filename+logname,tbl);
                disp("saving:"+filename+logname);
                file_id = file_id +1;
            end
        end
        %%将Optitrack的数据加入缓冲区，缓冲区满data_max_sn后写入文件
        function logOptitrackData(app,newtbl)
            persistent Otbl;
            persistent sn;
            if(isempty(newtbl))
                app.logOptitrackDataToFile(Otbl);
                Otbl = [];
                return;
            end
            if(isempty(Otbl))
                varTypes = ["int32","int32","string","double","double","double","double","double","double","double","string"];
                varNames = ["SN","FrameIndex","RigidName","x","y","z","ox","oy","oz","timestamp","remark"];
                sz = [app.data_max_sn,11];
                Otbl = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                sn = 1;

                Otbl(1,:) = [sn,newtbl];
            else
                sn = sn+1;
                Otbl(sn,:)=[sn,newtbl];
            end
            if sn>app.data_max_sn
                app.logOptitrackDataToFile(Otbl);
                sn = 0;
                Otbl = [];
            end

        end
        %%将Optitrack的数据写入文件
        function logOptitrackDataToFile(app,tbl)
            persistent file_id;
            if(isempty(file_id))
                file_id = 0;
            end
            if(~isempty(tbl))
                tbl(:,1) = file_id*10000+tbl(:,1);
                logname = "\"+num2str(file_id,"%06d")+".parquet";
                parquetwrite(app.optitrackLogFilename+logname,tbl);
                disp("saving:"+app.optitrackLogFilename+logname);
                file_id = file_id +1;
            end
        end


        %%将磁场传感器的数据加入缓冲区，缓冲区满data_max_sn后写入文件

        function logMagSensorData(app,newtbl)
            %若新加入的数据为空，则将表数据写入文件后清空表
            persistent Btbl
            persistent sn_B
            if isempty(newtbl)
                app.logMagSensorDataToFile(Btbl,app.magSensorLogFilename);
                Btbl = [];
                sn_B = 0;
                return
            end

            if(isempty(Btbl))%若表为空，初始化表后，将新加入数据写到表的第一行
                varTypes = ["int32","int8","double","double","double","double","double","string"];
                varName = ["SN","id","Bx","By","Bz","T","timestamp","remark"];
                sz = [app.data_max_sn,8];
                Btbl = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varName);
                Btbl(1,:) = [1,newtbl];
                sn_B = 1;
            else%表不空，将新加入数据写到表的最后一行
                
                sn_B = sn_B+1;
                Btbl(sn_B ,:)=[sn_B,newtbl];
            end
            %当表数据大于最大值时，将表数据写入文件后清空表
            if sn_B >app.data_max_sn
                app.logMagSensorDataToFile(Btbl,app.magSensorLogFilename);
                sn_B = 0;
            end

        end
        
        function logMagSensorData_Continious(app,newtbl)

            persistent Btbl_C
            persistent sn_BC
            %若新加入的数据为空，则将表数据写入文件后清空表
            if isempty(newtbl)
                app.logMagSensorDataToFile(Btbl_C,app.magSensorLogFilename_Continious);
                Btbl_C = [];
                sn_BC = 0;
                return
            end

            if(isempty(Btbl_C))%若表为空，初始化表后，将新加入数据写到表的第一行
                varTypes = ["int32","int8","double","double","double","double","double","string"];
                varName = ["SN","id","Bx","By","Bz","T","timestamp","remark"];
                sz = [app.data_max_sn,8];
                Btbl_C = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varName);
                Btbl_C(1,:) = [1,newtbl];
                sn_BC = 1;
            else%表不空，将新加入数据写到表的最后一行
                sn_BC = sn_BC+1;
                Btbl_C(sn_BC,:)=[sn_BC,newtbl];
                
                
            end
            %当表数据大于最大值时，将表数据写入文件后清空表
            if sn_BC >app.data_max_sn
                app.logMagSensorDataToFile(Btbl_C,app.magSensorLogFilename_Continious);
                sn_BC = 0;
                Btbl_C = [];
            end

        end

        function logMagSensorDataToFile(app,tbl,filename)
            persistent file_id;
            if(isempty(file_id))
                file_id = 0;
            end
            if(~isempty(tbl))
                tbl(:,1) = file_id*10000+tbl(:,1);
                logname = "\"+num2str(file_id,"%06d")+".parquet";
                parquetwrite(filename+logname,tbl);
                disp("saving:"+filename+logname);
                file_id = file_id +1;
            end
        end

        function f = loadSetupPara(app,filename)

            fcontent = fileread(filename);
            f = jsondecode(fcontent);
            app.sample_threshold  = f.sample_saving_threshold;
            R_noise = f.sensor_measure_noise';
            P_thre = f.sensor_kalman_reboot_threshold;
            Q = f.sensor_Q;
            app.xkn = cell(1,app.sensor_amount);
            app.Pkn = cell(1,app.sensor_amount);
            app.R = cell(1,app.sensor_amount);
            app.P_threshold = cell(1,app.sensor_amount);

            for i = 1:app.sensor_amount
                app.xkn{i} = zeros(3,1);
                app.Pkn{i} = eye(3)*10000;
                app.R{i} = diag(R_noise(:,i));
                app.P_threshold{i} = P_thre(i);
                app.sample_continuous_count{i} = 0;
            end
            app.kalman_reset_flag = false(app.sensor_amount,1);
            app.reset_count_011 = 0;
            app.Q = f.sensor_Q;

        end


        %%%%%%%%%%%%%%%%%%%%%%%%
        function process_data_ble(app)
            %app.store_received_bytes
            frame_begin = [];
            frame_end = [];
            store_receive_temp = app.store_received_bytes;
            %disp(app.store_received_bytes)
            % 新的解码处理，屈服于for循环
            for i = 1:length(store_receive_temp)
                if i+app.frame_length-1 > length(store_receive_temp)
                    break
                end
                if store_receive_temp(i) == app.beginning && store_receive_temp(i+app.frame_length-1) == app.ending

                    bytes = store_receive_temp(i:i+app.frame_length-1);
                    app.byte_decode_ble(bytes);
                    %frame_begin = [frame_begin, i];
                    %frame_end = [frame_end,i+app.frame_length-1];
                    frame_begin = i;
                    frame_end = i+app.frame_length-1;
                    i = i + app.frame_length;
                end
            end

            if ~isempty(frame_begin)
                %for j = 1:length(frame_begin)
                % app.store_received_bytes(frame_begin(j)-app.frame_length*(j-1) : frame_end(j)-app.frame_length*(j-1)) = [];
                %end
                app.store_received_bytes(1:frame_end) = [];
            end
            if length(app.store_received_bytes) > 100 * app.frame_length
                app.store_received_bytes = [];
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%
        function byte_decode_ble(app, bytes)
            id = bytes(2)+1;
            if id> app.sensor_amount
                return
            end
            Bx = typecast((uint8(bytes(3:6))), 'single');
            By = typecast((uint8(bytes(7:10))), 'single');
            Bz = typecast((uint8(bytes(11:14))), 'single');
            time = seconds(datetime("now")-app.start_time);

            app.mag_data_queue_id{id}.append([Bx,By,Bz,time]');
            
            % app.mag_data_queue(id,1,app.rear(id)) = Bx;
            % app.mag_data_queue(id,2,app.rear(id)) = By;
            % app.mag_data_queue(id,3,app.rear(id)) = Bz;
            % 
            % 
            % 
            % app.mag_data_queue(id,4,app.rear(id)) = time;

            % if id == app.id_receive.Value
            %     last_rear = app.rear(id)-1;
            %     if last_rear == 0
            %         last_rear = app.queue_size;
            %     end
            %     app.RateEditField.Value = 1/(time-app.mag_data_queue(id,4,last_rear));
            % end
            % app.rear(id) = mod(app.rear(id),app.queue_size)+1;

            app.display_receive_data([Bx;By;Bz],id,0);

            %当且仅当synclabelCheckBox为true时记录Btbl，存放至app.Btbl队列中
            if app.synclabelCheckBox.Value

                label_text =string(app.labelTextArea.Value)+":"+num2str(app.syncEditField.Value,"%04d");
                newtbl = {int8(id),Bx,By,Bz,0.0,time,label_text};
                app.Btbl_temp(app.Btbl_temp_sn,:)=newtbl;
                app.Btbl_temp_sn = app.Btbl_temp_sn+1;
                app.logMagSensorData(newtbl);

            end


        end


        function [pose,deg] = get_urpose_panel(app,id)
            switch id
                case 0
                    x = app.xEditField.Value;
                    y = app.yEditField.Value;
                    z = app.zEditField.Value;
                    rx = app.rxEditField.Value;
                    ry = app.ryEditField.Value;
                    rz = app.rzEditField.Value;

                    j1 = app.j1EditField.Value;
                    j2 = app.j2EditField.Value;
                    j3 = app.j3EditField.Value;
                    j4 = app.j4EditField.Value;
                    j5 = app.j5EditField.Value;
                    j6 = app.j6EditField.Value;
                case 1
                    j1 = app.j1EditField_2.Value;
                    j2 = app.j2EditField_2.Value;
                    j3 = app.j3EditField_2.Value;
                    j4 = app.j4EditField_2.Value;
                    j5 = app.j5EditField_2.Value;
                    j6 = app.j6EditField_2.Value;
                    x = app.xEditField_2.Value;
                    y = app.yEditField_2.Value;
                    z = app.zEditField_2.Value;
                    rx = app.rxEditField_2.Value;
                    ry = app.ryEditField_2.Value;
                    rz = app.rzEditField_2.Value;
                case 2
                    j1 = app.j1EditField_3.Value;
                    j2 = app.j2EditField_3.Value;
                    j3 = app.j3EditField_3.Value;
                    j4 = app.j4EditField_3.Value;
                    j5 = app.j5EditField_3.Value;
                    j6 = app.j6EditField_3.Value;
                    x = app.xEditField_3.Value;
                    y = app.yEditField_3.Value;
                    z = app.zEditField_3.Value;
                    rx = app.rxEditField_3.Value;
                    ry = app.ryEditField_3.Value;
                    rz = app.rzEditField_3.Value;


            end
            pose = [x,y,z,rx,ry,rz];
            deg = [j1,j2,j3,j4,j5,j6];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BLE)
            % 蓝牙定时器
            timer_init_ble(app);

            % 坐标树
            app.di = frame_graph(digraph,app.SxAxes);
            app.di.addnodes_json("上位机\frame.json");%digraph


            %rootpath settings
            app.start_time = datetime("now");
            a = string(datetime("now","Format","uuuuMMdd'T'HHmmss"));
            app.UIFigure.Name = a;

            %app.RootFilename = "D:\Program\3_axis_magnetic_sensor\DataLog\"+a;
            app.RootFilename = string(pwd)+"\DataLog\"+a;
            app.magSensorLogFilename = app.RootFilename +"\MagSensorDataLog";

            app.magSensorLogFilename_Continious = app.RootFilename +"\MagSensorDataLog_Continious";

            app.optitrackLogFilename =  app.RootFilename +"\OptitrackDataLog";
            app.URPoseLogFilename = app.RootFilename + "\URPoseDataLog";

            app.URPoseLogFilename_Continious = app.RootFilename + "\URPoseDataLog_Continious";

            app.sampleFilename = app.RootFilename+"\SampleFile";
            app.paramFilename = app.RootFilename+"\ParaFile";
            %mkdir(app.sampleFilename);
            mkdir(app.magSensorLogFilename);
            mkdir(app.magSensorLogFilename_Continious);
            %mkdir(app.paramFilename);
            %mkdir(app.optitrackLogFilename)
            mkdir(app.URPoseLogFilename);
            mkdir(app.URPoseLogFilename_Continious);
            %Btbl settings
            app.data_max_sn = 1000;%单文件的最大记录个数
            sz = [app.data_max_sn,8];
            varTypes = ["int32","int8","double","double","double","double","double","string"];
            varName = ["SN","id","Bx","By","Bz","T","timestamp","remark"];
            app.Btbl = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varName);
            app.Btbl_temp = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varName);
            app.Btbl_temp_sn = 1;
            app.data_sn = 1;%数据记录初始序号

            %hysteresis status settings
            app.Sx = [];
            app.Sy = [];
            app.Sz = [];


            %timer settings
            app.timer_init();

            %serial settings
            app.com_selection_receive.Items = serialportlist;
            app.close_serial_receive.Enable = 'off';

            %queue settings
            app.sensor_amount = 20;
            app.queue_size = 2048;
            %app.mag_data_queue = zeros(app.sensor_amount,4,app.queue_size);

            app.mag_data_queue_id = {};
            for i = 1:app.sensor_amount
            app.mag_data_queue_id{i} = fifo_queue(app.queue_size,"U"+i);
            end
            app.mag_data_queue_id_draw = fifo_queue(app.queue_size,"Draw");
            app.front = ones(1,app.sensor_amount);
            app.rear = ones(1,app.sensor_amount);
            app.residual_data = [];

            %text_buf settings
            app.text_size = 5;
            app.text_buf = strings(app.text_size,1);
            app.text_front = 1;
            app.text_rear = 1;
            app.continuous_id = 1;
            app.receive_buf = fifo_queue(100,"receive_buf");

            % app.loadSetupPara("上位机/app2Setup.json");


            %sample settings
            app.sample_change_flag = false;
            app.sample_continuous_count = cell(1,app.sensor_amount);
            app.sample_data = cell(1,app.sensor_amount);
            app.sample_append_flag = 0;
            app.cal_invA = cell(1,app.sensor_amount);
            app.cal_b = cell(1,app.sensor_amount);

            %读取sample_threshold
            % sample_file = "D:\Program\3_axis_magnetic_sensor\Sensor_Calibrate\Sample_threshold.txt";
            % f = fopen(threshold_file,'r');
            % sample_threshold = textscan(f,"%f");
            % app.sample_threshold = [sample_threshold{:}];
            % fclose(f);


            %绘图设定
            app.an_x = animatedline(app.LineAxes,'LineStyle','-','MaximumNumPoints',app.queue_size,'Color',[0 0.4470 0.7410]);
            app.an_y = animatedline(app.LineAxes,'LineStyle','-','MaximumNumPoints',app.queue_size,'Color',[0.8500 0.3250 0.0980]);
            app.an_z = animatedline(app.LineAxes,'LineStyle','-','MaximumNumPoints',app.queue_size,'Color',[0.9290 0.6940 0.1250]);
            app.an_B = animatedline(app.LineAxes,'LineStyle','--','MaximumNumPoints',app.queue_size,'Color',[0 0 0]);
            app.an_last_head = ones(1,app.sensor_amount);


            %assignin("base","app",app);

        end

        % Value changed function: id_receive
        function id_receiveValueChanged(app, event)
            id = app.id_receive.Value;
            
            if app.StartButton.Enable ==false
                clearpoints(app.an_x);
                clearpoints(app.an_y);
                clearpoints(app.an_z);
                clearpoints(app.an_B);
                app.mag_data_queue_id_draw.clear;
                %app.an_last_head = app.front;
                app.mag_data_queue_id_draw.copy_data(app.mag_data_queue_id{id});
            end
            %修改sample面板
            
            if id>app.sensor_amount ||id<1
                return
            end
            caltbl = table(app.cal_invA{id},app.cal_b{id});
            app.CalibParaTable.Data = caltbl;
            [~,sample_size] = size(app.sample_data{id});
            app.SamplenumberEditField.Value = sample_size;
        end

        % Drop down opening function: com_selection_receive
        function com_selection_receiveValueChanged(app, event)
            app.com_selection_receive.Items = serialportlist;
        end

        % Button pushed function: open_serial_receive
        function open_serial_receiveButtonPushed(app, event)
            % app.receive_serial =
            % serialport(app.com_selection_receive.Value,512000); BLE mode
            app.receive_serial = serialport(app.com_selection_receive.Value,115200);
            app.close_serial_receive.Enable = 'on';
            app.open_serial_receive.Enable = 'off';
            app.receive_serial.Parity = "none";
            app.receive_serial.DataBits = 8;
            app.receive_serial.StopBits = 1;
            app.receive_serial.ByteOrder = "little-endian";
            app.receive_serial.Timeout = 10;
            configureCallback(app.receive_serial,"byte",200,@app.ReadSerialData);
            start(app.Timer_id3);
        end

        % Button pushed function: close_serial_receive
        function close_serial_receiveButtonPushed(app, event)
            app.receive_serial = [];
            app.close_serial_receive.Enable = 'off';
            app.open_serial_receive.Enable = 'on';
            stop(app.Timer_id3);
        end

        % Button pushed function: loadParametersButton
        function loadParametersButtonPushed(app, event)
            %Remain to be done
            app.loadSetupPara("上位机/app2Setup.json");
        end

        % Button pushed function: get_geomagnetismButton
        function get_geomagnetismButtonPushed(app, event)

            %.mag_data_queue（id，1，app.rear（id））=B（1）；app.mag_data_queue（id，2，app.rear（id））=B（2）；app.mag_data_queue（id，3，app.rear（id））=B（3）；
            for id = 1:app.sensor_amount

                app.geomagnetism(:,id) = app.get_last_mag(id);
                disp([app.geomagnetism(1,id);app.geomagnetism(2,id);app.geomagnetism(3,id)]);
            end
        end

        % Callback function
        function ButtonPushed(app, event)
            app.sample_append_flag = app.sample_append_flag+1;
        end

        % Callback function
        function Button_2Pushed(app, event)
            app.sample_append_flag = app.sample_append_flag-1;
        end

        % Button pushed function: SaveSampleButton
        function SaveSampleButtonPushed(app, event)
            sample_data_backup = app.sample_data;
            UnitMagnet = app.UniteMagnet.Value;
            save_path = app.sampleFilename;
            filename = "sample+"+string(datetime("now","Format","uuuuMMdd'T'HHmmss"))+".mat";
            save(save_path+"\"+filename,"sample_data_backup","UnitMagnet");
            save("sample_data.mat","sample_data_backup","UnitMagnet");
        end

        % Button pushed function: SaveParamButton
        function SaveParamButtonPushed(app, event)
            cal_invA_backup = app.cal_invA;
            cal_b_backup = app.cal_b;
            save_path = app.paramFilename;
            save(save_path+"\"+"caliberation_parameters.mat","cal_invA_backup","cal_b_backup");
            save("caliberation_parameters.mat","cal_invA_backup","cal_b_backup");
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.sample_data = cell(1,app.sensor_amount);

            app.SamplenumberEditField.Value = 0;
            app.sample_append_flag = 0;
            app.ContinuousSaveCheckBox.Value = false;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            F = app.UniteMagnet.Value;%uT
            for id = 1:app.sensor_amount
                data=app.sample_data{id};
                n = length(data);
                if n == 0
                    disp("id:"+num2str(id)+" has no samples");
                    continue
                end
                [M,N,d]=app.ellipsoid_fit(data);
                app.cal_b{id} = -inv(M)*N;
                app.cal_invA{id}= F/sqrt(-N.'*app.cal_b{id}-d)*sqrtm(M) ;

            end

            id = app.id_receive.Value;
            if(id>app.sensor_amount||id<1)
                return
            end
            caltbl = table(app.cal_invA{id},app.cal_b{id});
            app.CalibParaTable.Data = caltbl;
        end

        % Button pushed function: loadSampleButton
        function loadSampleButtonPushed(app, event)
            filename = "sample_data.mat";
            sample_data_backup= load(filename,"sample_data_backup");
            app.sample_data = sample_data_backup.sample_data_backup;
            id = app.id_receive.Value;
            if(id>app.sensor_amount||id<1)
                return
            end
            app.SamplenumberEditField.Value = length(app.sample_data{id});
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % lim = 5000;
            % set(app.LineAxes, 'Box', 'off', ...
            %     'XAxisLocation', 'origin', ...
            %     'YAxisLocation', 'origin', ...
            %     'TickLabelInterpreter', 'latex', ...
            %     'FontName', 'Times New Roman');
            % % % Provide a very beautiful figure.
            % axis(app.SxAxes,"equal");
            % sc = 1.5;
            % xlim(app.SxAxes,[-sc*lim, sc*lim]);
            % xticks(app.SxAxes,[]);
            % ylim(app.SxAxes,[-sc*lim, sc*lim]);
            % yticks(app.SxAxes,[]);
            % xlabel(app.SxAxes,'\beta');
            % ylabel(app.SxAxes,'\alpha');
            % set(app.SxAxes, 'Box', 'off', ...
            %     'XAxisLocation', 'origin', ...
            %     'YAxisLocation', 'origin', ...
            %     'TickLabelInterpreter', 'latex', ...
            %     'FontName', 'Times New Roman');
            % % % Provide a very beautiful figure.
            % axis(app.SyAxes,"equal");
            % sc = 1.5;
            % xlim(app.SyAxes,[-sc*lim, sc*lim]);
            % xticks(app.SyAxes,[]);
            % ylim(app.SyAxes,[-sc*lim, sc*lim]);
            % yticks(app.SyAxes,[]);
            % xlabel(app.SyAxes,'\beta');
            % ylabel(app.SyAxes,'\alpha');
            % set(app.SyAxes, 'Box', 'off', ...
            %     'XAxisLocation', 'origin', ...
            %     'YAxisLocation', 'origin', ...
            %     'TickLabelInterpreter', 'latex', ...
            %     'FontName', 'Times New Roman');
            %
            % % % Provide a very beautiful figure.
            % axis(app.SzAxes,"equal");
            % sc = 1.5;
            % xlim(app.SzAxes,[-sc*lim, sc*lim]);
            % xticks(app.SzAxes,[]);
            % ylim(app.SzAxes,[-sc*lim, sc*lim]);
            % yticks(app.SzAxes,[]);
            % xlabel(app.SzAxes,'\beta');
            % ylabel(app.SzAxes,'\alpha');
            % set(app.SzAxes, 'Box', 'off', ...
            %     'XAxisLocation', 'origin', ...
            %     'YAxisLocation', 'origin', ...
            %     'TickLabelInterpreter', 'latex', ...
            %     'FontName', 'Times New Roman');
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            if app.SamplenumberEditField.Value >0
                app.sample_change_flag = true;
            end
            start(app.Timer_id1);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            stop(app.Timer_id1);
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            %app.mag_data_queue = zeros(app.sensor_amount,4,app.queue_size);
            % app.front = ones(1,app.sensor_amount);
            % app.rear = ones(1,app.sensor_amount);
            % app.an_last_head = ones(1,app.sensor_amount);
            for i = 1:app.sensor_amount
            app.mag_data_queue_id{i}.clear;
            end
            clearpoints(app.an_x);
            clearpoints(app.an_y);
            clearpoints(app.an_z);
            clearpoints(app.an_B);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app.Timer_id_2);
            app.logMagSensorData([]);%将表数据写入文件后清空表
            app.logMagSensorData_Continious([]);%将表数据写入文件后清空表
            %app.logOptitrackData([]);
            app.logURPoseData([],app.URPoseLogFilename);
            app.logURPoseData_Continious([]);
            if(~isempty(timerfind))
                stop(timerfind);
                delete(timerfind)
            end

            delete(app.ur);
            delete(app.obj)
            delete(app)

        end

        % Button pushed function: optistartButton
        function optistartButtonPushed(app, event)

            app.opticloseButton.Enable = 'on';
            app.opticloseButton.Visible = 'on';
            app.optistartButton.Enable = 'off';
            app.optistartButton.Visible = 'off';
            start(app.Timer_id5);


        end

        % Button pushed function: opticloseButton
        function opticloseButtonPushed(app, event)
            app.opticloseButton.Enable = 'off';
            app.opticloseButton.Visible = 'off';
            app.optistartButton.Enable = 'on';
            app.optistartButton.Visible = 'on';
            stop(app.Timer_id5);

        end

        % Button pushed function: dispstartButton
        function dispstartButtonPushed(app, event)
            app.dispcloseButton.Enable = 'on';
            app.dispcloseButton.Visible = 'on';
            app.dispstartButton.Enable = 'off';
            app.dispstartButton.Visible = 'off';
            start(app.Timer_id6);
        end

        % Button pushed function: dispcloseButton
        function dispcloseButtonPushed(app, event)
            app.dispcloseButton.Enable = 'off';
            app.dispcloseButton.Visible = 'off';
            app.dispstartButton.Enable = 'on';
            app.dispstartButton.Visible = 'on';
            stop(app.Timer_id6);
        end

        % Button pushed function: AddButton
        function AddButtonPushed2(app, event)
            app.sample_append_flag = app.sample_append_flag+1;
        end

        % Button pushed function: Button_2
        function Button_2Pushed2(app, event)
            app.sample_append_flag = app.sample_append_flag-1;
        end

        % Button pushed function: optiinitializeButton
        function optiinitializeButtonPushed(app, event)
            %optitrack settings
            app.obj = OptiTrack;
            app.obj.Initialize;
            app.optiinitializeButton.Visible = 'off';
            app.optiinitializeButton.Enable = 'off';
        end

        % Value changed function: synclabelCheckBox
        function synclabelCheckBoxValueChanged(app, event)
            if app.synclabelCheckBox.Value
                % if(strcmp(app.optiinitializeButton.Enable,'on')||strcmp(app.optistartButton.Enable,'on'))&&~strcmp(app.TabGroup2.SelectedTab.Title,'UR')
                %     app.synclabelCheckBox.Value = false;
                %     return
                % end
                persistent text_value;
                %若第一次进入该函数，将syncEditField置0
                if isempty(text_value)
                    text_value = app.labelTextArea.Value;
                    app.syncEditField.Value = 0;
                end
                %若第记录的text_value发生改变，将syncEditField置0
                if ~strcmp(app.labelTextArea.Value,text_value)
                    text_value = app.labelTextArea.Value;
                    app.syncEditField.Value = 0;
                end
                start(app.Timer_id7);
            else
                stop(app.Timer_id7);
            end

        end

        % Button pushed function: UR_Queue_Initialize_Button
        function UR_Queue_Initialize_ButtonPushed(app, event)
            app.ur = ur(app);

            app.AddButton_UR.Enable = "on";
            app.URExecuteButton.Enable = "on";
            app.ExecutePanelButton.Enable = "on";
            app.URSyncButton.Enable = "on";
            app.frameDropDown.Items = app.di.get_all_nodes;
            app.objectDropDown.Items = app.di.get_all_nodes;
            app.frameDropDown_2.Items = app.di.get_all_nodes;
            app.objectDropDown_2.Items = app.di.get_all_nodes;
            app.frameDropDown_3.Items = app.di.get_all_nodes;
            app.objectDropDown_3.Items = app.di.get_all_nodes;
            app.URClearQueueButton.Enable = "on";
        end

        % Button pushed function: AddButton_UR
        function AddButton_URPushed(app, event)
            %局部变量定义及其初始化
            persistent msg;
            if isempty(msg)
                msg = struct("stamp",0,"ur0",struct,"ur1",struct,"remark","");
            end
            app.URSyncButtonPushed([]);

            [~,deg0] = app.get_urpose_panel(0);
            %这里默认记录movej的数据
            ur0_std_frame = struct(...
                "id",0,...
                "mode",2,...
                "pose",zeros(1,6),...
                "deg",deg0,...
                "enable",true,...
                "relative",false...
                );
            [~,deg1] = app.get_urpose_panel(1);
            ur1_std_frame = struct(...
                "id",1,...
                "mode",2,...
                "pose",zeros(1,6),...
                "deg",deg1,...
                "enable",true,...
                "relative",false...
                );
            msg.ur0 = ur0_std_frame;
            msg.ur1 = ur1_std_frame;
            msg.stamp = msg.stamp+1;
            remark_text = string(app.labelTextArea.Value)+":"+num2str(app.syncEditField.Value,"%04d");
            msg.remark = remark_text;
            app.ur.URMsgQueue.append(msg);
            app.URActionLen.Value = app.ur.URMsgQueue.len;

        end

        % Button pushed function: SubButton_UR
        function SubButton_URPushed(app, event)
            if(app.URActionLen.Value==0)
                return
            end
            app.URActionLen.Value = app.URActionLen.Value-1;
            app.syncEditField.Value = max(0,app.syncEditField.Value-1);
            app.ur.URMsgQueue.pop();
            %删除app.Btbl队列中与当下remark相同的条目的数据
            %此处可能有bug,注意测试！！！！！！！！！！！！！！！！！！！！！！！
            %！！！！！！！！！！！！！！！！！！！！！！！！！！！！！！！！！！
            rf = rowfilter("remark");
            remark_text  = string(app.labelTextArea.Value)+":"+num2str(app.syncEditField.Value,"%04d");
            app.Btbl(rf.remark==remark_text,:)=[];
            app.data_sn = app.data_sn - (app.data_max_sn-height(app.Btbl));
            disp(app.data_sn)
        end

        % Button pushed function: SaveURMsgToFileButton
        function SaveURMsgToFileButtonPushed(app, event)
            if(app.URActionLen.Value == 0)
                return
            end

            app.ur.URMsgQueue.set_filename(app.UIFigure.Name);
            app.ur.URMsgQueue.log_data_to_file("json");
            app.ur.URMsgQueue.clear();
            app.URActionLen.Value = 0;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [selected_file,selpath] = uigetfile('./functions/UR_planning/Vessel_URMsg/*.json');
            file = dir(selpath);
            a = [];
            for i = 1:length(file)
                if ~file(i).isdir
                    a{end+1}=file(i).name;
                end
            end
            app.URTextArea.Value = selpath;
            app.URDropDown.Items = a;
            app.URDropDown.Value =selected_file;
            app.LoadURMsgToFileButtonPushed(event);
        end

        % Button pushed function: LoadURMsgToFileButton
        function LoadURMsgToFileButtonPushed(app, event)
            file_dir = string(app.URTextArea.Value)+'\'+string(app.URDropDown.Value);
            fjson = fileread(file_dir);
            fjson = jsondecode(fjson);
            app.ur.URMsgQueue.clear();
            for i = 1:length(fjson)
                app.ur.URMsgQueue.append(fjson(i));
            end
            app.URActionLen.Value = app.ur.URMsgQueue.len;
        end

        % Callback function
        function URNextCommandButtonPushed(app, event)
            if(app.URCommandIndexSpinner.Value>=app.URActionLen.Value)
                return
            end
            app.URCommandIndexSpinner.Value = app.URCommandIndexSpinner.Value+1;

        end

        % Callback function
        function URLastCommandButtonPushed(app, event)
            if(app.URCommandIndexSpinner.Value<=1)
                return
            end
            app.URCommandIndexSpinner.Value = app.URCommandIndexSpinner.Value-1;
        end

        % Button pushed function: URExecuteButton
        function URExecuteButtonButtonPushed(app, event)
            index = app.URCommandIndexSpinner.Value;
            if(index>app.URActionLen.Value||index<1)
                return
            end
            ur_std_msg = app.ur.URMsgQueue.index(index);
            ur_std_msg.ur0.enable = ur_std_msg.ur0.enable&app.UR0EnableCheckBox.Value;
            ur_std_msg.ur1.enable = ur_std_msg.ur1.enable&app.UR1EnableCheckBox.Value;
            try
                ur_std_msg.ur2.enable = ur_std_msg.ur2.enable&app.UR2EnableCheckBox.Value;
            catch
            end
            app.ur.set_single_msg(ur_std_msg);
        end

        % Button pushed function: BLEConnectButton
        function BLEConnectButtonPushed(app, event)
            % nested fcn
            % function displayCharacteristicData(src,~)
            %     if ~app.BlockCheckBox.Value
            %         [data, ~] = read(src,"oldest");
            %         disp(data)
            %         app.store_received_bytes = [app.store_received_bytes, data];
            %         assignin("base","store_received_bytes",app.store_received_bytes);
            %         app.BLELengthEditField.Value = length(app.store_received_bytes);
            %         if(length(app.store_received_bytes)>240*5)
            %             app.store_received_bytes = [];
            %         end
            %     else
            %         unsubscribe(app.c_read_ble);
            %     end
            % end
            %try
            % 需要事先用 Blutooth LE Explorer预热一下，不然搜索不到 -_-!
            %新板子（绿灯）
            app.bluetooth_F170A479AA6A = ble("FBE9B9A336B0");
            %旧板子（红灯）
            %app.bluetooth_F170A479AA6A = ble("FBB886F5A936");

            start(app.Timer_id_2);
            app.c_read_ble = characteristic(app.bluetooth_F170A479AA6A,'FFF0','FFF1');
            subscribe(app.c_read_ble)
            app.c_read_ble.DataAvailableFcn = {@displayCharacteristicData,app};

            %app.c_write_ble  = characteristic(app.bluetooth_F170A479AA6A,'FFF0','FFF2');
            app.ble_connect_or_not = 1;
            app.ConnectLamp.Color = 'green';

            app.BLEConnectButton.Enable = false;
            app.BLECloseButton.Enable = true;
            % catch
            %     warndlg('Please connect BLE again', 'warning');
            %     app.ble_connect_or_not = 0;
            %     app.ConnectLamp.Color = 'red';
            % end
        end

        % Button pushed function: URSyncButton
        function URSyncButtonPushed(app, event)
            %ur0相关——————————————————————————————
            % app.ur.data_ready = false;
            % app.ur.get_msg(0);
            % data_temp = [];
            % i = 1;
            % while isempty(data_temp)
            %     waitfor(app.ur,"data_ready",true);
            %     if app.ur.data_temp.id == 0
            %         data_temp = app.ur.data_temp;
            %     end
            %     i = i+1;
            %     if(i>10)
            %         return;
            %     end
            % end
            % i = 1;
            % app.ur.data_temp = [];
            % if data_temp.enable
            %     app.UR0_Lamp.Color = [0,1,0];
            %     %将位姿更新注册在ur0Tcp坐标中
            %     h = app.di.d.Nodes.Handle("ur0Tcp");
            %     h.set_u(data_temp.pose(1:3));
            %     h.set_theta(data_temp.pose(4:6));
            %     app.update_UR_status_Panel(data_temp);
            % else
            %     h = app.di.d.Nodes.Handle("ur0Tcp");
            %     h.set_u(data_temp.pose(1:3));
            %     h.set_theta(data_temp.pose(4:6));
            %     app.update_UR_status_Panel(data_temp);
            %     app.UR0_Lamp.Color = [1,0,0];ur
            % end


            %ur1相关——————————————————————————————
            i = 1;
            app.ur.data_ready = false;
            app.ur.get_msg(1);
            data_temp = [];
            while isempty(data_temp)

                waitfor(app.ur,"data_ready",true);
                if app.ur.data_temp.id == 1
                    data_temp = app.ur.data_temp;
                end
                i = i+1;
                if(i>10)
                    return;
                end
            end
            app.ur.data_temp = [];
            if data_temp.enable
                app.UR1_Lamp.Color = [0,1,0];
                %将位姿的更新注册在ur1Tcp坐标中
                h = app.di.d.Nodes.Handle("ur1Tcp");
                h.set_u(data_temp.pose(1:3));
                h.set_theta(data_temp.pose(4:6));
                app.update_UR_status_Panel(data_temp);
                assignin("base","di",app.di);
            else
                h = app.di.d.Nodes.Handle("ur1Tcp");
                h.set_u(data_temp.pose(1:3));
                h.set_theta(data_temp.pose(4:6));
                app.update_UR_status_Panel(data_temp);
                app.UR1_Lamp.Color = [1,0,0];
            end


            %ur2相关——————————————————————————————
            app.ur.data_ready = false;
            try
                app.ur.get_msg(2);
                data_temp = [];
                while isempty(data_temp)

                    waitfor(app.ur,"data_ready",true);
                    if app.ur.data_temp.id == 2
                        data_temp = app.ur.data_temp;
                    end
                    i = i+1;
                    if(i>10)
                        return;
                    end
                end
                app.ur.data_temp = [];
                if data_temp.enable
                    app.UR2_Lamp.Color = [0,1,0];
                    %将位姿的更新注册在ur2Tcp坐标中
                    h = app.di.d.Nodes.Handle("ur2Tcp");
                    h.set_u(data_temp.pose(1:3));
                    h.set_theta(data_temp.pose(4:6));
                    app.update_UR_status_Panel(data_temp);
                    assignin("base","di",app.di);
                else
                    h = app.di.d.Nodes.Handle("ur2Tcp");
                    h.set_u(data_temp.pose(1:3));
                    h.set_theta(data_temp.pose(4:6));
                    app.update_UR_status_Panel(data_temp);
                    app.UR2_Lamp.Color = [1,0,0];
                end
            catch
            end
        end

        % Value changed function: URDropDown
        function URDropDownValueChanged(app, event)
            app.LoadURMsgToFileButtonPushed(event)
        end

        % Value changed function: URAutoNextButton
        function URAutoNextButtonValueChanged(app, event)
            buttonvalue = app.URAutoNextButton.Value;
            if buttonvalue==false %若为按下的状态，直接返回
                stop(app.Timer_id4);
                return
            end
            start(app.Timer_id4);
        end

        % Button pushed function: ExecutePanelButton
        function ExecutePanelButtonPushed(app, event)

            [pose0,deg0] = app.get_urpose_panel(0);
            dst_node = app.frameDropDown.Value;
            obj_node  = app.objectDropDown.Value;
            base0_pose_o= app.di.get_pose_at(pose0,dst_node,"base0");
            [ur0Tcp_R_obj,ur0Tcp_T_obj] = app.di.get_transfer(obj_node,"ur0Tcp");
            base0_R_ur0Tcp = Exp(base0_pose_o(4:6))*ur0Tcp_R_obj';
            base0_T_ur0Tcp = reshape(base0_pose_o(1:3),[3,1])-base0_R_ur0Tcp*ur0Tcp_T_obj;

            pose0 = [reshape(base0_T_ur0Tcp,[1,3]),reshape(Log(base0_R_ur0Tcp),[1,3])];

            ur0Enable = app.UR0EnableCheckBox.Value;

            switch app.UR0modeDropDown.Value
                case "1:movel"
                    ur0_std_frame = struct(...
                        "id",0,...
                        "mode",1,...
                        "pose",pose0,...
                        "deg",zeros(1,6),...
                        "enable",ur0Enable,...
                        "relative",false...
                        );
                case "2:movej"
                    ur0_std_frame = struct(...
                        "id",0,...
                        "mode",2,...
                        "pose",zeros(1,6),...
                        "deg",deg0,...
                        "enable",ur0Enable,...
                        "relative",false...
                        );

            end

            [pose1,deg1] = app.get_urpose_panel(1);

            dst_node = app.frameDropDown_2.Value;
            obj_node  = app.objectDropDown_2.Value;
            base1_pose_o= app.di.get_pose_at(pose1,dst_node,"base1");
            [ur1Tcp_R_obj,ur1Tcp_T_obj] = app.di.get_transfer(obj_node,"ur1Tcp");
            base1_R_ur1Tcp = Exp(base1_pose_o(4:6))*ur1Tcp_R_obj';
            base1_T_ur1Tcp = reshape(base1_pose_o(1:3),[3,1])-base1_R_ur1Tcp*ur1Tcp_T_obj;
            pose1 = [reshape(base1_T_ur1Tcp,[1,3]),reshape(Log(base1_R_ur1Tcp),[1,3])];

            ur1Enable = app.UR1EnableCheckBox.Value;
            switch app.UR1modeDropDown.Value
                case "1:movel"
                    ur1_std_frame = struct(...
                        "id",1,...
                        "mode",1,...
                        "pose",pose1,...
                        "deg",zeros(1,6),...
                        "enable",ur1Enable,...
                        "relative",false...
                        );
                case "2:movej"
                    ur1_std_frame = struct(...
                        "id",1,...
                        "mode",2,...
                        "pose",zeros(1,6),...
                        "deg",deg1,...
                        "enable",ur1Enable,...
                        "relative",false...
                        );

            end


            [pose2,deg2] = app.get_urpose_panel(2);
 
            dst_node = app.frameDropDown_3.Value;
            obj_node  = app.objectDropDown_3.Value;
            base2_pose_o= app.di.get_pose_at(pose2,dst_node,"base2");
            [ur2Tcp_R_obj,ur2Tcp_T_obj] = app.di.get_transfer(obj_node,"ur2Tcp");
            base2_R_ur2Tcp = Exp(base2_pose_o(4:6))*ur2Tcp_R_obj';
            base2_T_ur2Tcp = reshape(base2_pose_o(1:3),[3,1])-base2_R_ur2Tcp*ur2Tcp_T_obj;
            pose2 = [reshape(base2_T_ur2Tcp,[1,3]),reshape(Log(base2_R_ur2Tcp),[1,3])];

            ur2Enable = app.UR2EnableCheckBox.Value;
            switch app.UR2modeDropDown.Value
                case "1:movel"
                    ur2_std_frame = struct(...
                        "id",2,...
                        "mode",1,...
                        "pose",pose2,...
                        "deg",zeros(1,6),...
                        "enable",ur2Enable,...
                        "relative",false...
                        );
                case "2:movej"
                    ur2_std_frame = struct(...
                        "id",2,...
                        "mode",2,...
                        "pose",zeros(1,6),...
                        "deg",deg2,...
                        "enable",ur2Enable,...
                        "relative",false...
                        );

            end
            %ur1_std_frame = struct("id",1,"mode",0,"pose",zeros(1,6),"deg",zeros(1,6),"enable",false,"relative",true);
            msg_len = app.ur.URMsgQueue.len;
            msg = struct("stamp",msg_len+1,"ur0",ur0_std_frame,"ur1",ur1_std_frame,"ur2",ur2_std_frame,"remark","");
            disp(msg.ur0)
            disp(msg.ur1)
            disp(msg.ur2)
            app.ur.set_single_msg(msg);

            %更新一下面板
            app.URSyncButtonPushed(event);
            app.URSyncButtonPushed(event);

        end

        % Value changed function: StepEditField
        function StepEditFieldValueChanged(app, event)
            value = app.StepEditField.Value;
            app.xEditField.Step = value;
            app.yEditField.Step = value;
            app.zEditField.Step = value;
            app.rxEditField.Step = value;
            app.ryEditField.Step = value;
            app.rzEditField.Step = value;
            app.j1EditField.Step = value;
            app.j2EditField.Step = value;
            app.j3EditField.Step = value;
            app.j4EditField.Step = value;
            app.j5EditField.Step = value;
            app.j6EditField.Step = value;

        end

        % Value changed function: frameDropDown
        function frameDropDownValueChanged(app, event)
            app.URSyncButtonPushed(event);
        end

        % Value changed function: URAutoLastButton
        function URAutoLastButtonValueChanged(app, event)
            buttonvalue = app.URAutoNextButton.Value;
            if ~buttonvalue %若为按下的状态，直接返回
                return
            end
            start(app.Timer_id4);
        end

        % Button pushed function: PlotDigraphButton
        function PlotDigraphButtonPushed(app, event)
            app.di.set_axis(app.SxAxes);
            plot(app.di);
        end

        % Button pushed function: Button_6
        function Button_4Pushed(app, event)
            app.di.set_axis(app.SyAxes);
            app.di.show_all_at_base(["id"+[0:15],"SensorPose1","SmallMagnetCenter","ur1Tcp"]);
        end

        % Drop down opening function: frameDropDown_2
        function frameDropDown_2Opening(app, event)
            app.frameDropDown_2.Items = app.di.get_all_nodes;
            app.objectDropDown_2.Items = app.di.get_all_nodes;
        end

        % Value changed function: frameDropDown_2
        function frameDropDown_2ValueChanged(app, event)
            app.URSyncButtonPushed(event);

        end

        % Value changed function: StepEditField_2
        function StepEditField_2ValueChanged(app, event)
            value = app.StepEditField_2.Value;
            app.xEditField_2.Step = value;
            app.yEditField_2.Step = value;
            app.zEditField_2.Step = value;
            app.rxEditField_2.Step = value;
            app.ryEditField_2.Step = value;
            app.rzEditField_2.Step = value;
            app.j1EditField_2.Step = value;
            app.j2EditField_2.Step = value;
            app.j3EditField_2.Step = value;
            app.j4EditField_2.Step = value;
            app.j5EditField_2.Step = value;
            app.j6EditField_2.Step = value;
        end

        % Button pushed function: ReloadCoordTreeButton
        function ReloadCoordTreeButtonPushed(app, event)
            app.di = frame_graph(digraph,app.SxAxes);
            app.di.addnodes_json("上位机\frame.json");%digraph
            app.frameDropDown.Items = app.di.get_all_nodes;
            app.objectDropDown.Items = app.di.get_all_nodes;
            app.frameDropDown_2.Items = app.di.get_all_nodes;
            app.objectDropDown_2.Items = app.di.get_all_nodes;
        end

        % Button pushed function: BLECloseButton
        function BLECloseButtonPushed(app, event)
            %delete(app.bluetooth_F170A479AA6A);
            app.bluetooth_F170A479AA6A = [];
            stop(app.Timer_id_2);
            app.BLEConnectButton.Enable = "on";
            app.BLECloseButton.Enable = "off";
            app.ConnectLamp.Color = [1,0,0];
        end

        % Button pushed function: URClearQueueButton
        function URClearQueueButtonPushed(app, event)
            app.ur.URMsgQueue.clear();
            app.URActionLen.Value = 0;
        end

        % Button pushed function: SaveURPoseButton
        function SaveURPoseButtonPushed(app, event)
            app.URSyncButtonPushed(event);
            label_text =string(app.labelTextArea.Value)+":"+num2str(app.syncEditField.Value,"%04d");
            time = seconds(datetime("now")-app.start_time);

            frame_name = "BigMagnetF";
            pose = app.di.get_frame_pose(frame_name,"base1");
            newtbl = {frame_name,pose(1),pose(2),pose(3),pose(4),pose(5),pose(6),time,label_text};
            app.logURPoseData(newtbl,app.URPoseLogFilename);

            for id = 0:app.sensor_amount-1
                frame_name = "id"+id;
                pose = app.di.get_frame_pose(frame_name,"base1");
                newtbl = {frame_name,pose(1),pose(2),pose(3),pose(4),pose(5),pose(6),time,label_text};
                app.logURPoseData(newtbl,app.URPoseLogFilename);
            end


        end

        % Button pushed function: Button_7
        function Button_6Pushed(app, event)

            msg = app.ur.URMsgQueue.index(app.URCommandIndexSpinner.Value);
            ur1_pose = msg.ur1.pose;
            R = Exp(ur1_pose(4:6));
            T = ur1_pose(1:3);
            hold(app.SyAxes,"on");
            px = poseplot(app.SyAxes,R',T,"ENU",ScaleFactor=50);
            px.DisplayName = "next";
            text(app.SyAxes,T(1)+35,T(2)+35,T(3)+50,"UR1_next");
        end

        % Callback function
        function ClearBLEBufferEditFieldValueChanged(app, event)

        end

        % Button pushed function: ShuffleButton
        function ShuffleButtonPushed(app, event)
            subscribe(app.c_read_ble)
            % function displayCharacteristicData(src,~)
            %     if ~app.BlockCheckBox.Value
            %         %subscribe(app.c_read_ble);
            %         [data, ~] = read(src,"oldest");
            %         disp(data)
            %         app.store_received_bytes = [app.store_received_bytes, data];
            %         assignin("base","store_received_bytes",app.store_received_bytes);
            %         app.BLELengthEditField.Value = length(app.store_received_bytes);
            %         if(length(app.store_received_bytes)>240*5)
            %             app.store_received_bytes = [];
            %         end
            %     else
            %         unsubscribe(app.c_read_ble);
            %         app.c_read_ble.DataAvailableFcn = [];
            %     end
            % end
            % data = read(app.c_read_ble);
            % app.store_received_bytes = data;
            % app.c_read_ble.DataAvailableFcn = @displayCharacteristicData;
        end

        % Drop down opening function: UR0modeDropDown
        function UR0modeDropDownOpening(app, event)

        end

        % Drop down opening function: frameDropDown
        function frameDropDownOpening(app, event)

        end

        % Drop down opening function: objectDropDown
        function objectDropDownOpening(app, event)

        end

        % Value changed function: StepEditField_3
        function StepEditField_3ValueChanged(app, event)
            value = app.StepEditField_3.Value;
            app.xEditField_3.Step = value;
            app.yEditField_3.Step = value;
            app.zEditField_3.Step = value;
            app.rxEditField_3.Step = value;
            app.ryEditField_3.Step = value;
            app.rzEditField_3.Step = value;
            app.j1EditField_3.Step = value;
            app.j2EditField_3.Step = value;
            app.j3EditField_3.Step = value;
            app.j4EditField_3.Step = value;
            app.j5EditField_3.Step = value;
            app.j6EditField_3.Step = value;

        end

        % Value changed function: frameDropDown_3
        function frameDropDown_3ValueChanged(app, event)
            value = app.frameDropDown_3.Value;
            
        end

        % Drop down opening function: frameDropDown_3
        function frameDropDown_3Opening(app, event)
            app.frameDropDown_3.Items = app.di.get_all_nodes;
            app.objectDropDown_3.Items = app.di.get_all_nodes;
        end

        % Value changed function: objectDropDown_3
        function objectDropDown_3ValueChanged(app, event)

            app.URSyncButtonPushed(event);

        end

        % Value changed function: objectDropDown_2
        function objectDropDown_2ValueChanged(app, event)
            app.URSyncButtonPushed(event);

        end

        % Value changed function: objectDropDown
        function objectDropDownValueChanged(app, event)
            app.URSyncButtonPushed(event);
        end

        % Button pushed function: CaliUR
        function CaliURPushed(app, event)
            label_text = '标定UR';
            app.URSyncButtonPushed([]);
            frame_name = "ur2Tcp";
            pose = app.di.get_frame_pose(frame_name,"base2");
            newtbl = {frame_name,pose(1),pose(2),pose(3),pose(4),pose(5),pose(6),0,label_text};
            app.logURPoseData_Continious(newtbl);

            frame_name = "ur1Tcp";
            pose = app.di.get_frame_pose(frame_name,"base1");
            newtbl = {frame_name,pose(1),pose(2),pose(3),pose(4),pose(5),pose(6),0,label_text};
            app.logURPoseData_Continious(newtbl);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {874, 874, 874};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {874, 874};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x', 473};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.ReceivePanel = uipanel(app.GridLayout3);
            app.ReceivePanel.Title = 'Receive Panel';
            app.ReceivePanelGrid = uigridlayout(app.ReceivePanel);
            app.open_serial_receive = uibutton(app.ReceivePanelGrid, 'push');
            app.open_serial_receive.ButtonPushedFcn = createCallbackFcn(app, @open_serial_receiveButtonPushed, true);
            app.open_serial_receive.Text = 'open serial';
            app.close_serial_receive = uibutton(app.ReceivePanelGrid, 'push');
            app.close_serial_receive.ButtonPushedFcn = createCallbackFcn(app, @close_serial_receiveButtonPushed, true);
            app.close_serial_receive.Enable = 'off';
            app.close_serial_receive.Text = 'close serial';
            app.get_geomagnetismButton = uibutton(app.ReceivePanelGrid, 'push');
            app.get_geomagnetismButton.ButtonPushedFcn = createCallbackFcn(app, @get_geomagnetismButtonPushed, true);
            app.get_geomagnetismButton.Text = 'get_geomagnetism';
            app.loadParametersButton = uibutton(app.ReceivePanelGrid, 'push');
            app.loadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @loadParametersButtonPushed, true);
            app.loadParametersButton.Text = 'load Parameters';
            app.calibrationCheckBox = uicheckbox(app.ReceivePanelGrid);
            app.calibrationCheckBox.Text = 'calibration';
            app.justbiasCheckBox = uicheckbox(app.ReceivePanelGrid);
            app.justbiasCheckBox.Text = 'just bias';
            app.portnumberLabel = uilabel(app.ReceivePanelGrid);
            app.portnumberLabel.Text = 'PN';
            app.com_selection_receive = uidropdown(app.ReceivePanelGrid);
            app.com_selection_receive.Items = {'COM4'};
            app.com_selection_receive.DropDownOpeningFcn = createCallbackFcn(app, @com_selection_receiveValueChanged, true);
            app.com_selection_receive.Value = 'COM4';
            app.id_receive = uispinner(app.ReceivePanelGrid);
            app.id_receive.ValueChangedFcn = createCallbackFcn(app, @id_receiveValueChanged, true);
            app.id_receive.Value = 1;
            app.idSpinner_2Label = uilabel(app.ReceivePanelGrid);
            app.idSpinner_2Label.Text = 'id';
            app.BxEditField_2Label = uilabel(app.ReceivePanelGrid);
            app.BxEditField_2Label.Text = 'Bx';
            app.Bx_receive = uieditfield(app.ReceivePanelGrid, 'numeric');
            app.Bx_receive.ValueDisplayFormat = '%11.6g';
            app.Bx_receive.Editable = 'off';
            app.ByEditField_2Label = uilabel(app.ReceivePanelGrid);
            app.ByEditField_2Label.Text = 'By';
            app.By_receive = uieditfield(app.ReceivePanelGrid, 'numeric');
            app.By_receive.ValueDisplayFormat = '%11.6g';
            app.By_receive.Editable = 'off';
            app.BzEditField_2Label = uilabel(app.ReceivePanelGrid);
            app.BzEditField_2Label.Text = 'Bz';
            app.Bz_receive = uieditfield(app.ReceivePanelGrid, 'numeric');
            app.Bz_receive.ValueDisplayFormat = '%11.6g';
            app.Bz_receive.Editable = 'off';
            app.BzEditField_2Label_2 = uilabel(app.ReceivePanelGrid);
            app.BzEditField_2Label_2.Text = 'T';
            app.T_receive = uieditfield(app.ReceivePanelGrid, 'numeric');
            app.T_receive.ValueDisplayFormat = '%11.6g';
            app.T_receive.Editable = 'off';
            app.BzEditField_2Label_3 = uilabel(app.ReceivePanelGrid);
            app.BzEditField_2Label_3.Text = 'B';
            app.B_receive = uieditfield(app.ReceivePanelGrid, 'numeric');
            app.B_receive.ValueDisplayFormat = '%11.6g';
            app.B_receive.Editable = 'off';
            app.RateEditFieldLabel = uilabel(app.ReceivePanelGrid);
            app.RateEditFieldLabel.Text = 'Rate';
            app.RateEditField = uieditfield(app.ReceivePanelGrid, 'numeric');
            app.RateEditField.Editable = 'off';
            app.kalmanCheckBox = uicheckbox(app.ReceivePanelGrid);
            app.kalmanCheckBox.Text = 'kalman';
            app.autozeroCheckBox = uicheckbox(app.ReceivePanelGrid);
            app.autozeroCheckBox.Text = 'autozero';
            app.TextPanel = uipanel(app.GridLayout3);
            app.TextPanel.Title = 'Text Panel';
            app.GridLayout4 = uigridlayout(app.TextPanel);
            app.text_receive = uitextarea(app.GridLayout4);
            app.text_receive.Editable = 'off';
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.TabGroup.Visible = 'off';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.GridLayout8 = uigridlayout(app.CalibrationTab);
            app.CalculateButton = uibutton(app.GridLayout8, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ClearButton = uibutton(app.GridLayout8, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SaveSampleButton = uibutton(app.GridLayout8, 'push');
            app.SaveSampleButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSampleButtonPushed, true);
            app.SaveSampleButton.Text = 'Save Sample';
            app.loadSampleButton = uibutton(app.GridLayout8, 'push');
            app.loadSampleButton.ButtonPushedFcn = createCallbackFcn(app, @loadSampleButtonPushed, true);
            app.loadSampleButton.Text = 'load Sample';
            app.SaveParamButton = uibutton(app.GridLayout8, 'push');
            app.SaveParamButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamButtonPushed, true);
            app.SaveParamButton.Text = 'Save Param';
            app.CalibParaLabel = uilabel(app.GridLayout8);
            app.CalibParaLabel.Text = 'Calib Para:';
            app.CalibParaTable = uitable(app.GridLayout8);
            app.CalibParaTable.ColumnName = {'invA'; 'b'};
            app.CalibParaTable.RowName = {};
            app.ContinuousSaveCheckBox = uicheckbox(app.GridLayout8);
            app.ContinuousSaveCheckBox.Text = 'Conti Save';
            app.BzEditField_2Label_4 = uilabel(app.GridLayout8);
            app.BzEditField_2Label_4.Text = 'UniMag';
            app.UniteMagnet = uieditfield(app.GridLayout8, 'numeric');
            app.UniteMagnet.Value = 50;
            app.SamplenumberEditField = uieditfield(app.GridLayout8, 'numeric');
            app.AddButton = uibutton(app.GridLayout8, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed2, true);
            app.AddButton.Text = '+';
            app.Button_2 = uibutton(app.GridLayout8, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed2, true);
            app.Button_2.Text = '-';
            app.BLETab = uitab(app.TabGroup);
            app.BLETab.Title = 'BLE';
            app.GridLayout14 = uigridlayout(app.BLETab);
            app.BLEConnectButton = uibutton(app.GridLayout14, 'push');
            app.BLEConnectButton.ButtonPushedFcn = createCallbackFcn(app, @BLEConnectButtonPushed, true);
            app.BLEConnectButton.Text = 'BLE Connect';
            app.ConnectLampLabel = uilabel(app.GridLayout14);
            app.ConnectLampLabel.Text = 'Connect';
            app.ConnectLamp = uilamp(app.GridLayout14);
            app.BLECloseButton = uibutton(app.GridLayout14, 'push');
            app.BLECloseButton.ButtonPushedFcn = createCallbackFcn(app, @BLECloseButtonPushed, true);
            app.BLECloseButton.Enable = 'off';
            app.BLECloseButton.Text = 'BLE Close';
            app.BLELengthEditField = uieditfield(app.GridLayout14, 'numeric');
            app.ShuffleButton = uibutton(app.GridLayout14, 'push');
            app.ShuffleButton.ButtonPushedFcn = createCallbackFcn(app, @ShuffleButtonPushed, true);
            app.ShuffleButton.Text = 'Shuffle';
            app.BlockCheckBox = uicheckbox(app.GridLayout14);
            app.BlockCheckBox.Text = 'Block';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.CenterPanel);
            app.ScatterAxes = uiaxes(app.GridLayout5);
            title(app.ScatterAxes, 'scatter graph')
            xlabel(app.ScatterAxes, 't')
            ylabel(app.ScatterAxes, 'B(uT)')
            zlabel(app.ScatterAxes, 'Z')
            app.SyAxes = uiaxes(app.GridLayout5);
            title(app.SyAxes, 'Sy')
            xlabel(app.SyAxes, '\alpha')
            ylabel(app.SyAxes, '\beta')
            app.SxAxes = uiaxes(app.GridLayout5);
            title(app.SxAxes, 'Sx')
            xlabel(app.SxAxes, '\beta')
            ylabel(app.SxAxes, '\alpha')
            app.LineAxes = uiaxes(app.GridLayout5);
            title(app.LineAxes, 'Magnetic strength graph')
            xlabel(app.LineAxes, 't')
            ylabel(app.LineAxes, 'B(uT)')
            zlabel(app.LineAxes, 'Z')
            app.DisplayPanel = uipanel(app.GridLayout5);
            app.DisplayPanel.Title = 'Display Panel';
            app.GridLayout6 = uigridlayout(app.DisplayPanel);
            app.StartButton = uibutton(app.GridLayout6, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout6, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ClearDataButton = uibutton(app.GridLayout6, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.LineCheckBox = uicheckbox(app.GridLayout6);
            app.LineCheckBox.Text = 'Line';
            app.LineCheckBox.Value = true;
            app.scatterCheckBox = uicheckbox(app.GridLayout6);
            app.scatterCheckBox.Text = 'scatter';
            app.scatterCheckBox.Value = true;
            app.polyLineCheckBox = uicheckbox(app.GridLayout6);
            app.polyLineCheckBox.Text = 'polyLine';
            app.polyLineCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.RightPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout7);
            app.URTab = uitab(app.TabGroup2);
            app.URTab.Title = 'UR';
            app.GridLayout12 = uigridlayout(app.URTab);
            app.Panel = uipanel(app.GridLayout12);
            app.Panel.Title = 'Panel';
            app.GridLayout11 = uigridlayout(app.Panel);
            app.SaveURMsgToFileButton = uibutton(app.GridLayout11, 'push');
            app.SaveURMsgToFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveURMsgToFileButtonPushed, true);
            app.SaveURMsgToFileButton.Text = '保存';
            app.LoadURMsgToFileButton = uibutton(app.GridLayout11, 'push');
            app.LoadURMsgToFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadURMsgToFileButtonPushed, true);
            app.LoadURMsgToFileButton.Text = '载入';
            app.BrowseButton = uibutton(app.GridLayout11, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = '浏览';
            app.Label_3 = uilabel(app.GridLayout11);
            app.Label_3.Text = '路径';
            app.URTextArea = uitextarea(app.GridLayout11);
            app.Label_4 = uilabel(app.GridLayout11);
            app.Label_4.Text = '文件名';
            app.URDropDown = uidropdown(app.GridLayout11);
            app.URDropDown.Items = {'Current.json', '000000.json', 'Option 3', 'Option 4'};
            app.URDropDown.ValueChangedFcn = createCallbackFcn(app, @URDropDownValueChanged, true);
            app.URDropDown.Value = 'Current.json';
            app.UR_Queue_Initialize_Button = uibutton(app.GridLayout11, 'push');
            app.UR_Queue_Initialize_Button.ButtonPushedFcn = createCallbackFcn(app, @UR_Queue_Initialize_ButtonPushed, true);
            app.UR_Queue_Initialize_Button.Text = 'UR初始化';
            app.coordinatetreePanel = uipanel(app.GridLayout12);
            app.coordinatetreePanel.Title = 'coordinate tree';
            app.GridLayout13 = uigridlayout(app.coordinatetreePanel);
            app.PlotDigraphButton = uibutton(app.GridLayout13, 'push');
            app.PlotDigraphButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDigraphButtonPushed, true);
            app.PlotDigraphButton.Text = '显示';
            app.Button_6 = uibutton(app.GridLayout13, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_6.Text = '姿态';
            app.ReloadCoordTreeButton = uibutton(app.GridLayout13, 'push');
            app.ReloadCoordTreeButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadCoordTreeButtonPushed, true);
            app.ReloadCoordTreeButton.Text = '重载';
            app.SaveURPoseButton = uibutton(app.GridLayout13, 'push');
            app.SaveURPoseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveURPoseButtonPushed, true);
            app.SaveURPoseButton.Text = '保存';
            app.Button_7 = uibutton(app.GridLayout13, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_7.Text = '预览';
            app.CaliUR = uibutton(app.GridLayout13, 'push');
            app.CaliUR.ButtonPushedFcn = createCallbackFcn(app, @CaliURPushed, true);
            app.CaliUR.Text = '标定';
            app.Panel2 = uipanel(app.GridLayout12);
            app.Panel2.Title = 'Panel2';
            app.DisplayTab = uitab(app.TabGroup2);
            app.DisplayTab.Title = 'Display';
            app.GridLayout15 = uigridlayout(app.DisplayTab);
            app.UR0StatusPanel = uipanel(app.GridLayout15);
            app.UR0StatusPanel.Title = 'UR0 Status Panel';
            app.GridLayout10_3 = uigridlayout(app.UR0StatusPanel);
            app.UR0_Lamp = uilamp(app.GridLayout10_3);
            app.j1SpinnerLabel_3 = uilabel(app.GridLayout10_3);
            app.j1SpinnerLabel_3.Text = 'j1';
            app.j1EditField = uispinner(app.GridLayout10_3);
            app.j1EditField.Step = 0.01;
            app.j2Label_3 = uilabel(app.GridLayout10_3);
            app.j2Label_3.Text = 'j2';
            app.j2EditField = uispinner(app.GridLayout10_3);
            app.j2EditField.Step = 0.01;
            app.j3Label_3 = uilabel(app.GridLayout10_3);
            app.j3Label_3.Text = 'j3';
            app.j3EditField = uispinner(app.GridLayout10_3);
            app.j3EditField.Step = 0.01;
            app.j4Label_19 = uilabel(app.GridLayout10_3);
            app.j4Label_19.Text = 'j4';
            app.j4EditField = uispinner(app.GridLayout10_3);
            app.j4EditField.Step = 0.01;
            app.j4Label_20 = uilabel(app.GridLayout10_3);
            app.j4Label_20.Text = 'j5';
            app.j5EditField = uispinner(app.GridLayout10_3);
            app.j5EditField.Step = 0.01;
            app.j4Label_21 = uilabel(app.GridLayout10_3);
            app.j4Label_21.Text = 'j6';
            app.j6EditField = uispinner(app.GridLayout10_3);
            app.j6EditField.Step = 0.01;
            app.j4Label_22 = uilabel(app.GridLayout10_3);
            app.j4Label_22.Text = 'x';
            app.xEditField = uispinner(app.GridLayout10_3);
            app.xEditField.Step = 0.01;
            app.j4Label_23 = uilabel(app.GridLayout10_3);
            app.j4Label_23.Text = 'y';
            app.yEditField = uispinner(app.GridLayout10_3);
            app.yEditField.Step = 0.01;
            app.j4Label_24 = uilabel(app.GridLayout10_3);
            app.j4Label_24.Text = 'z';
            app.zEditField = uispinner(app.GridLayout10_3);
            app.zEditField.Step = 0.01;
            app.j4Label_25 = uilabel(app.GridLayout10_3);
            app.j4Label_25.Text = 'rx';
            app.rxEditField = uispinner(app.GridLayout10_3);
            app.rxEditField.Step = 0.01;
            app.j4Label_26 = uilabel(app.GridLayout10_3);
            app.j4Label_26.Text = 'ry';
            app.ryEditField = uispinner(app.GridLayout10_3);
            app.ryEditField.Step = 0.01;
            app.j4Label_27 = uilabel(app.GridLayout10_3);
            app.j4Label_27.Text = 'rz';
            app.rzEditField = uispinner(app.GridLayout10_3);
            app.rzEditField.Step = 0.01;
            app.StepEditField = uieditfield(app.GridLayout10_3, 'numeric');
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.StepEditField.Value = 0.01;
            app.UR0modeDropDown = uidropdown(app.GridLayout10_3);
            app.UR0modeDropDown.Items = {'1:movel', '2:movej'};
            app.UR0modeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @UR0modeDropDownOpening, true);
            app.UR0modeDropDown.Value = '1:movel';
            app.frameDropDown_3Label_3 = uilabel(app.GridLayout10_3);
            app.frameDropDown_3Label_3.Text = 'frame';
            app.frameDropDown = uidropdown(app.GridLayout10_3);
            app.frameDropDown.Items = {'base0', 'base1'};
            app.frameDropDown.DropDownOpeningFcn = createCallbackFcn(app, @frameDropDownOpening, true);
            app.frameDropDown.ValueChangedFcn = createCallbackFcn(app, @frameDropDownValueChanged, true);
            app.frameDropDown.Value = 'base0';
            app.frameDropDown_3Label_4 = uilabel(app.GridLayout10_3);
            app.frameDropDown_3Label_4.Text = 'frame';
            app.objectDropDown = uidropdown(app.GridLayout10_3);
            app.objectDropDown.Items = {'id0'};
            app.objectDropDown.DropDownOpeningFcn = createCallbackFcn(app, @objectDropDownOpening, true);
            app.objectDropDown.ValueChangedFcn = createCallbackFcn(app, @objectDropDownValueChanged, true);
            app.objectDropDown.Value = 'id0';
            app.UR0EnableCheckBox = uicheckbox(app.GridLayout10_3);
            app.UR0EnableCheckBox.Text = 'UR0Enable';
            app.UR1StatusPanel = uipanel(app.GridLayout15);
            app.UR1StatusPanel.Title = 'UR1 Status Panel';
            app.GridLayout10_4 = uigridlayout(app.UR1StatusPanel);
            app.UR1_Lamp = uilamp(app.GridLayout10_4);
            app.j4Label_28 = uilabel(app.GridLayout10_4);
            app.j4Label_28.Text = 'x';
            app.xEditField_2 = uispinner(app.GridLayout10_4);
            app.xEditField_2.Step = 0.01;
            app.j4Label_29 = uilabel(app.GridLayout10_4);
            app.j4Label_29.Text = 'y';
            app.yEditField_2 = uispinner(app.GridLayout10_4);
            app.yEditField_2.Step = 0.01;
            app.j4Label_30 = uilabel(app.GridLayout10_4);
            app.j4Label_30.Text = 'z';
            app.zEditField_2 = uispinner(app.GridLayout10_4);
            app.zEditField_2.Step = 0.01;
            app.j4Label_31 = uilabel(app.GridLayout10_4);
            app.j4Label_31.Text = 'rx';
            app.rxEditField_2 = uispinner(app.GridLayout10_4);
            app.rxEditField_2.Step = 0.01;
            app.j4Label_32 = uilabel(app.GridLayout10_4);
            app.j4Label_32.Text = 'ry';
            app.ryEditField_2 = uispinner(app.GridLayout10_4);
            app.ryEditField_2.Step = 0.01;
            app.j4Label_33 = uilabel(app.GridLayout10_4);
            app.j4Label_33.Text = 'rz';
            app.rzEditField_2 = uispinner(app.GridLayout10_4);
            app.rzEditField_2.Step = 0.01;
            app.j1SpinnerLabel_4 = uilabel(app.GridLayout10_4);
            app.j1SpinnerLabel_4.Text = 'j1';
            app.j1EditField_2 = uispinner(app.GridLayout10_4);
            app.j1EditField_2.Step = 0.01;
            app.j2Label_4 = uilabel(app.GridLayout10_4);
            app.j2Label_4.Text = 'j2';
            app.j2EditField_2 = uispinner(app.GridLayout10_4);
            app.j2EditField_2.Step = 0.01;
            app.j3Label_4 = uilabel(app.GridLayout10_4);
            app.j3Label_4.Text = 'j3';
            app.j3EditField_2 = uispinner(app.GridLayout10_4);
            app.j3EditField_2.Step = 0.01;
            app.j4Label_34 = uilabel(app.GridLayout10_4);
            app.j4Label_34.Text = 'j4';
            app.j4EditField_2 = uispinner(app.GridLayout10_4);
            app.j4EditField_2.Step = 0.01;
            app.j4Label_35 = uilabel(app.GridLayout10_4);
            app.j4Label_35.Text = 'j5';
            app.j5EditField_2 = uispinner(app.GridLayout10_4);
            app.j5EditField_2.Step = 0.01;
            app.j4Label_36 = uilabel(app.GridLayout10_4);
            app.j4Label_36.Text = 'j6';
            app.j6EditField_2 = uispinner(app.GridLayout10_4);
            app.j6EditField_2.Step = 0.01;
            app.StepEditField_2 = uieditfield(app.GridLayout10_4, 'numeric');
            app.StepEditField_2.ValueChangedFcn = createCallbackFcn(app, @StepEditField_2ValueChanged, true);
            app.StepEditField_2.Value = 0.01;
            app.UR1modeDropDown = uidropdown(app.GridLayout10_4);
            app.UR1modeDropDown.Items = {'1:movel', '2:movej'};
            app.UR1modeDropDown.Value = '1:movel';
            app.frameDropDown_5Label = uilabel(app.GridLayout10_4);
            app.frameDropDown_5Label.Text = 'frame';
            app.frameDropDown_2 = uidropdown(app.GridLayout10_4);
            app.frameDropDown_2.Items = {'base0', 'base1'};
            app.frameDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @frameDropDown_2Opening, true);
            app.frameDropDown_2.ValueChangedFcn = createCallbackFcn(app, @frameDropDown_2ValueChanged, true);
            app.frameDropDown_2.Value = 'base1';
            app.frameDropDown_3Label_5 = uilabel(app.GridLayout10_4);
            app.frameDropDown_3Label_5.Text = 'frame';
            app.objectDropDown_2 = uidropdown(app.GridLayout10_4);
            app.objectDropDown_2.Items = {'MagnetF'};
            app.objectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @objectDropDown_2ValueChanged, true);
            app.objectDropDown_2.Value = 'MagnetF';
            app.UR1EnableCheckBox = uicheckbox(app.GridLayout10_4);
            app.UR1EnableCheckBox.Text = 'UR1Enable';
            app.UR2StatusPane = uipanel(app.GridLayout15);
            app.UR2StatusPane.Title = 'UR2 Status Panel';
            app.GridLayout10_5 = uigridlayout(app.UR2StatusPane);
            app.UR2_Lamp = uilamp(app.GridLayout10_5);
            app.j4Label_37 = uilabel(app.GridLayout10_5);
            app.j4Label_37.Text = 'x';
            app.xEditField_3 = uispinner(app.GridLayout10_5);
            app.xEditField_3.Step = 0.01;
            app.j4Label_38 = uilabel(app.GridLayout10_5);
            app.j4Label_38.Text = 'y';
            app.yEditField_3 = uispinner(app.GridLayout10_5);
            app.yEditField_3.Step = 0.01;
            app.j4Label_39 = uilabel(app.GridLayout10_5);
            app.j4Label_39.Text = 'z';
            app.zEditField_3 = uispinner(app.GridLayout10_5);
            app.zEditField_3.Step = 0.01;
            app.j4Label_40 = uilabel(app.GridLayout10_5);
            app.j4Label_40.Text = 'rx';
            app.rxEditField_3 = uispinner(app.GridLayout10_5);
            app.rxEditField_3.Step = 0.01;
            app.j4Label_41 = uilabel(app.GridLayout10_5);
            app.j4Label_41.Text = 'ry';
            app.ryEditField_3 = uispinner(app.GridLayout10_5);
            app.ryEditField_3.Step = 0.01;
            app.j4Label_42 = uilabel(app.GridLayout10_5);
            app.j4Label_42.Text = 'rz';
            app.rzEditField_3 = uispinner(app.GridLayout10_5);
            app.rzEditField_3.Step = 0.01;
            app.j1SpinnerLabel_5 = uilabel(app.GridLayout10_5);
            app.j1SpinnerLabel_5.Text = 'j1';
            app.j1EditField_3 = uispinner(app.GridLayout10_5);
            app.j1EditField_3.Step = 0.01;
            app.j2Label_5 = uilabel(app.GridLayout10_5);
            app.j2Label_5.Text = 'j2';
            app.j2EditField_3 = uispinner(app.GridLayout10_5);
            app.j2EditField_3.Step = 0.01;
            app.j3Label_5 = uilabel(app.GridLayout10_5);
            app.j3Label_5.Text = 'j3';
            app.j3EditField_3 = uispinner(app.GridLayout10_5);
            app.j3EditField_3.Step = 0.01;
            app.j4Label_43 = uilabel(app.GridLayout10_5);
            app.j4Label_43.Text = 'j4';
            app.j4EditField_3 = uispinner(app.GridLayout10_5);
            app.j4EditField_3.Step = 0.01;
            app.j4Label_44 = uilabel(app.GridLayout10_5);
            app.j4Label_44.Text = 'j5';
            app.j5EditField_3 = uispinner(app.GridLayout10_5);
            app.j5EditField_3.Step = 0.01;
            app.j4Label_45 = uilabel(app.GridLayout10_5);
            app.j4Label_45.Text = 'j6';
            app.j6EditField_3 = uispinner(app.GridLayout10_5);
            app.j6EditField_3.Step = 0.01;
            app.StepEditField_3 = uieditfield(app.GridLayout10_5, 'numeric');
            app.StepEditField_3.ValueChangedFcn = createCallbackFcn(app, @StepEditField_3ValueChanged, true);
            app.StepEditField_3.Value = 0.01;
            app.UR2modeDropDown = uidropdown(app.GridLayout10_5);
            app.UR2modeDropDown.Items = {'1:movel', '2:movej'};
            app.UR2modeDropDown.Value = '1:movel';
            app.frameDropDown_6Label = uilabel(app.GridLayout10_5);
            app.frameDropDown_6Label.Text = 'frame';
            app.frameDropDown_3 = uidropdown(app.GridLayout10_5);
            app.frameDropDown_3.Items = {'base0', 'base1'};
            app.frameDropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @frameDropDown_3Opening, true);
            app.frameDropDown_3.ValueChangedFcn = createCallbackFcn(app, @frameDropDown_3ValueChanged, true);
            app.frameDropDown_3.Value = 'base1';
            app.frameDropDown_3Label_6 = uilabel(app.GridLayout10_5);
            app.frameDropDown_3Label_6.Text = 'frame';
            app.objectDropDown_3 = uidropdown(app.GridLayout10_5);
            app.objectDropDown_3.Items = {'MagnetF'};
            app.objectDropDown_3.ValueChangedFcn = createCallbackFcn(app, @objectDropDown_3ValueChanged, true);
            app.objectDropDown_3.Value = 'MagnetF';
            app.UR2EnableCheckBox = uicheckbox(app.GridLayout10_5);
            app.UR2EnableCheckBox.Text = 'UR2Enable';
            app.OptitrackTab = uitab(app.TabGroup2);
            app.OptitrackTab.Title = 'Optitrack';
            app.GridLayout9 = uigridlayout(app.OptitrackTab);
            app.UIAxes = uiaxes(app.GridLayout9);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout9);
            app.UITable.ColumnName = {'Frame index'; 'position'; 'orientation'};
            app.UITable.RowName = {};
            app.optistartButton = uibutton(app.GridLayout9, 'push');
            app.optistartButton.ButtonPushedFcn = createCallbackFcn(app, @optistartButtonPushed, true);
            app.optistartButton.Text = 'opti-start';
            app.opticloseButton = uibutton(app.GridLayout9, 'push');
            app.opticloseButton.ButtonPushedFcn = createCallbackFcn(app, @opticloseButtonPushed, true);
            app.opticloseButton.Enable = 'off';
            app.opticloseButton.Visible = 'off';
            app.opticloseButton.Text = 'opti-close';
            app.SelectobjectDropDownLabel = uilabel(app.GridLayout9);
            app.SelectobjectDropDownLabel.Text = 'Select object';
            app.SelectobjectDropDown = uidropdown(app.GridLayout9);
            app.SelectobjectDropDown.Items = {'external M', 'sensor1', 'internal m'};
            app.SelectobjectDropDown.Value = 'sensor1';
            app.dispcloseButton = uibutton(app.GridLayout9, 'push');
            app.dispcloseButton.ButtonPushedFcn = createCallbackFcn(app, @dispcloseButtonPushed, true);
            app.dispcloseButton.Enable = 'off';
            app.dispcloseButton.Visible = 'off';
            app.dispcloseButton.Text = 'disp-close';
            app.dispstartButton = uibutton(app.GridLayout9, 'push');
            app.dispstartButton.ButtonPushedFcn = createCallbackFcn(app, @dispstartButtonPushed, true);
            app.dispstartButton.Text = 'disp-start';
            app.optiinitializeButton = uibutton(app.GridLayout9, 'push');
            app.optiinitializeButton.ButtonPushedFcn = createCallbackFcn(app, @optiinitializeButtonPushed, true);
            app.optiinitializeButton.Text = 'opti-initialize';
            app.LampLabel = uilabel(app.GridLayout9);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.GridLayout9);
            app.Panel3 = uipanel(app.GridLayout7);
            app.Panel3.Title = 'Panel3';
            app.GridLayout16 = uigridlayout(app.Panel3);
            app.AddButton_UR = uibutton(app.GridLayout16, 'push');
            app.AddButton_UR.ButtonPushedFcn = createCallbackFcn(app, @AddButton_URPushed, true);
            app.AddButton_UR.Enable = 'off';
            app.AddButton_UR.Text = '+';
            app.SubButton_UR = uibutton(app.GridLayout16, 'push');
            app.SubButton_UR.ButtonPushedFcn = createCallbackFcn(app, @SubButton_URPushed, true);
            app.SubButton_UR.Text = '-';
            app.URAutoNextButton = uibutton(app.GridLayout16, 'state');
            app.URAutoNextButton.ValueChangedFcn = createCallbackFcn(app, @URAutoNextButtonValueChanged, true);
            app.URAutoNextButton.Text = '向下回放';
            app.URAutoLastButton = uibutton(app.GridLayout16, 'state');
            app.URAutoLastButton.ValueChangedFcn = createCallbackFcn(app, @URAutoLastButtonValueChanged, true);
            app.URAutoLastButton.Text = '向上回放';
            app.URStopStatusButton = uibutton(app.GridLayout16, 'state');
            app.URStopStatusButton.Text = '停止';
            app.URSyncButton = uibutton(app.GridLayout16, 'push');
            app.URSyncButton.ButtonPushedFcn = createCallbackFcn(app, @URSyncButtonPushed, true);
            app.URSyncButton.Enable = 'off';
            app.URSyncButton.Text = '同步';
            app.URExecuteButton = uibutton(app.GridLayout16, 'push');
            app.URExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @URExecuteButtonButtonPushed, true);
            app.URExecuteButton.Enable = 'off';
            app.URExecuteButton.Text = '执行';
            app.ExecutePanelButton = uibutton(app.GridLayout16, 'push');
            app.ExecutePanelButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutePanelButtonPushed, true);
            app.ExecutePanelButton.Enable = 'off';
            app.ExecutePanelButton.Text = '执行面板';
            app.URClearQueueButton = uibutton(app.GridLayout16, 'push');
            app.URClearQueueButton.ButtonPushedFcn = createCallbackFcn(app, @URClearQueueButtonPushed, true);
            app.URClearQueueButton.Enable = 'off';
            app.URClearQueueButton.Text = '清空';
            app.synclabelCheckBox = uicheckbox(app.GridLayout16);
            app.synclabelCheckBox.ValueChangedFcn = createCallbackFcn(app, @synclabelCheckBoxValueChanged, true);
            app.synclabelCheckBox.Text = 'sync label';
            app.syncEditField = uieditfield(app.GridLayout16, 'numeric');
            app.Label_5 = uilabel(app.GridLayout16);
            app.Label_5.Text = 'maxlen';
            app.URActionLen = uieditfield(app.GridLayout16, 'numeric');
            app.labelTextAreaLabel = uilabel(app.GridLayout16);
            app.labelTextAreaLabel.Text = 'label';
            app.labelTextArea = uitextarea(app.GridLayout16);
            app.indexLabel = uilabel(app.GridLayout16);
            app.indexLabel.Text = 'index';
            app.URCommandIndexSpinner = uispinner(app.GridLayout16);
            app.Continious_saveCheckBox = uicheckbox(app.GridLayout16);
            app.Continious_saveCheckBox.Text = 'Continious_save';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end